classdef powerModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayoutMain                  matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SimulationGoalDropDown          matlab.ui.control.DropDown
        SimulationGoalDropDownLabel     matlab.ui.control.Label
        WarningTextArea                 matlab.ui.control.TextArea
        PowerModelGUILabel              matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        SimulationSettingsPanel         matlab.ui.container.Panel
        ResourceDataTypeDropDown        matlab.ui.control.DropDown
        ResourceDataTypeDropDownLabel   matlab.ui.control.Label
        DependentVariableDropDown       matlab.ui.control.DropDown
        DependentVariableDropDownLabel  matlab.ui.control.Label
        SimulationTimestepsEditField    matlab.ui.control.NumericEditField
        SimulationTimestepsEditFieldLabel  matlab.ui.control.Label
        SimulationDurationhrEditField   matlab.ui.control.NumericEditField
        SimulationDurationhrLabel       matlab.ui.control.Label
        ResourceDataWaveTimeSeriesUploadPanel  matlab.ui.container.Panel
        DatafileWaveTextArea            matlab.ui.control.TextArea
        DatafileTextAreaLabel           matlab.ui.control.Label
        DataisacsvtablecontainingtherequiredheadersCheckBox  matlab.ui.control.CheckBox
        SelectresourcedatafileWaveLabel  matlab.ui.control.Label
        BrowseWaveButton                matlab.ui.control.Button
        VariableSelectionWaveData       matlab.ui.container.Panel
        SelecttimevariableDropDown_2    matlab.ui.control.DropDown
        SelecttimevariableDropDown_2Label  matlab.ui.control.Label
        SelectpeakperiodvariableDropDown  matlab.ui.control.DropDown
        SelectpeakperiodvariableDropDownLabel  matlab.ui.control.Label
        SelectwaveenergyperiodvariableDropDown  matlab.ui.control.DropDown
        SelectwaveenergyperiodvariableDropDownLabel  matlab.ui.control.Label
        SelectsignificantwaveheightvariableDropDown  matlab.ui.control.DropDown
        SelectsignificantwaveheightvariableDropDownLabel  matlab.ui.control.Label
        ResourceDataPwrMatTimeSeriesUploadPanel  matlab.ui.container.Panel
        ApplygeneratorefficiencylossesCheckBox_2  matlab.ui.control.CheckBox
        DatafilePwrMatTextArea          matlab.ui.control.TextArea
        DatafileTextAreaLabel_2         matlab.ui.control.Label
        VariableSelectionPwrMatData     matlab.ui.container.Panel
        SelectwaveenergyperiodPwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label_3  matlab.ui.control.Label
        SelectsignificantwaveheightPwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label_2  matlab.ui.control.Label
        SelecttimevariablePwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label  matlab.ui.control.Label
        SelectpowervariablePwrMatDropDown  matlab.ui.control.DropDown
        SelectpowermatrixvariableLabel  matlab.ui.control.Label
        SelectresourcedatafilePwrMatLabel  matlab.ui.control.Label
        BrowsePwrMatButton              matlab.ui.control.Button
        ResourceDataPwrTimeSeriesUploadPanel  matlab.ui.container.Panel
        ApplygeneratorefficiencylossesCheckBox_3  matlab.ui.control.CheckBox
        DatafilePwrTextArea             matlab.ui.control.TextArea
        DatafileTextAreaLabel_3         matlab.ui.control.Label
        VariableSelectionPwrData        matlab.ui.container.Panel
        SelecttimevariableDropDown      matlab.ui.control.DropDown
        SelecttimevariableLabel         matlab.ui.control.Label
        SelectpowervariableDropDown     matlab.ui.control.DropDown
        SelectpowervariableDropDownLabel  matlab.ui.control.Label
        SelectresourcedatafilePwrLabel  matlab.ui.control.Label
        BrowsePwrButton                 matlab.ui.control.Button
        ResourceDataMeanPowerGenPanel   matlab.ui.container.Panel
        EntermeanpowergenerationWEditField  matlab.ui.control.EditField
        EntermeanpowergenerationWEditField_3Label  matlab.ui.control.Label
        ResourceDataMeanWavePanel       matlab.ui.container.Panel
        EntermeanwaveenergyperiodsEditField  matlab.ui.control.EditField
        EntermeanwaveenergyperiodsEditField_2Label_2  matlab.ui.control.Label
        EntermeansignificantwaveheightmEditField_2Label_2  matlab.ui.control.Label
        EntermeansignificantwaveheightmEditField  matlab.ui.control.EditField
        ResourceDataSeaStatePanel       matlab.ui.container.Panel
        SelectSeaStateDropDown          matlab.ui.control.DropDown
        SelectSeaStateLabel             matlab.ui.control.Label
        LimitMaxAUVFleetSizeEditField   matlab.ui.control.NumericEditField
        LimitMaxAUVFleetSizeCheckBox    matlab.ui.control.CheckBox
        StaggerAUVDeploymentsCheckBox   matlab.ui.control.CheckBox
        HardwareSpecsPanel              matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        WECTab                          matlab.ui.container.Tab
        BatteryEfficiency0XXWECEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXLabel       matlab.ui.control.Label
        GeneratorEfficiency0XXEditField  matlab.ui.control.NumericEditField
        GeneratorEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        HydrodynamicEfficiency0XXEditField  matlab.ui.control.NumericEditField
        HydrodynamicEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        BatteryCapacityWhWECEditField   matlab.ui.control.NumericEditField
        BatteryCapacityWhLabel          matlab.ui.control.Label
        HotelLoadWWECEditField          matlab.ui.control.NumericEditField
        HotelLoadWEditFieldLabel        matlab.ui.control.Label
        CharacteristicDimensionmEditField  matlab.ui.control.NumericEditField
        CharacteristicDimensionmEditFieldLabel  matlab.ui.control.Label
        ModelNameWECEditField           matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        AUVTab                          matlab.ui.container.Tab
        AddAUVButton                    matlab.ui.control.Button
        ConfiguredAUVsPanel             matlab.ui.container.Panel
        savedAUVdisplay                 matlab.ui.control.TextArea
        ModelNameAUVDropdown            matlab.ui.control.DropDown
        ModelNameLabel                  matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        BatteryCapacityWhAUVEditField   matlab.ui.control.NumericEditField
        MissionTimehrEditField          matlab.ui.control.NumericEditField
        MissionBatteryUsed0XXEditField  matlab.ui.control.NumericEditField
        HotelLoadWAUVEditField          matlab.ui.control.NumericEditField
        ChargeRateWEditField            matlab.ui.control.NumericEditField
        ChargingSwitch                  matlab.ui.control.Switch
        BatteryRechargeThreshold0XXEditField  matlab.ui.control.NumericEditField
        BatteryRechargeThreshold0XXEditFieldLabel  matlab.ui.control.Label
        BatteryEfficiency0XXAUVEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXEditField_2Label  matlab.ui.control.Label
        PowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        PowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        HotelLoadWEditField_2Label      matlab.ui.control.Label
        ChargingSwitchLabel             matlab.ui.control.Label
        ChargeRateWEditFieldLabel       matlab.ui.control.Label
        BatteryCapacityWhEditField_2Label  matlab.ui.control.Label
        MissionBatteryUsed0XXEditFieldLabel  matlab.ui.control.Label
        MissionTimehrEditFieldLabel     matlab.ui.control.Label
        MasskgEditFieldLabel            matlab.ui.control.Label
        EnergyStorageTab                matlab.ui.container.Tab
        BatteryCRate0XXEditField        matlab.ui.control.NumericEditField
        BatteryCRateLabel               matlab.ui.control.Label
        AUVDockHotelLoadWEditField      matlab.ui.control.NumericEditField
        AUVDockHotelLoadWEditFieldLabel  matlab.ui.control.Label
        BaseHotelLoadWEditField         matlab.ui.control.NumericEditField
        BaseHotelLoadWEditFieldLabel    matlab.ui.control.Label
        PredefinedCentralBatteryCapacityWhEditField  matlab.ui.control.NumericEditField
        PredefinedCentralBatteryCapacityWhCheckBox  matlab.ui.control.CheckBox
        WECPowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        WECPowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        AUVDockPowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        AUVDockPowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        BatteryEfficiency0XXESEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXEditField_3Label  matlab.ui.control.Label
        OutputPlotsPanel                matlab.ui.container.Panel
        GridLayoutOutputPlots           matlab.ui.container.GridLayout
        AggregateWorkingHoursvsDependentVariableCheckBox  matlab.ui.control.CheckBox
        AUVBatteryLevelsvsTimeCheckBox  matlab.ui.control.CheckBox
        CentralBatteryLevelvsTimeCheckBox  matlab.ui.control.CheckBox
        PowerGenerationvsTimeCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Defaults % Struct containing default startup values for all user-editable fields
        auvDefaultModels  % Struct containing data for default AUV models
        dataMetadata  % Struct containing data file name, path, and variable metadata from user-selected *.mat data file
        successfulBrowse % 'true' if user selected a file after prompting 'browse', 'false' if no file selected
        warnings  % Container class containing warnings
        auv AUV % AUV array containing user-input AUV properties

        resourceDataPanels  %  All resource data panels within the simulation settings panel 
        simSettingsvarSelectionPanels  % All the variable selection panels within the simulation settings panel 
        inputDataFileDisplays  % All of the input data file displays within the simulation settings panel
        allVarDropdowns  % All data variable selection dropdowns in the simulation settings panel
    end
    
    methods (Access = private)
        
        % Calculates AUV recharge efficiency according to charging method
        function getPowerTransferEfficiency(app)
            rechargeMethod = app.ChargingSwitch.Value;  % 1: wired, 0: wireless
            if rechargeMethod  % wired
                app.PowerTransferEfficiency0XXEditField.Value = 0.9;
            else  % wireless
                app.PowerTransferEfficiency0XXEditField.Value = 0.5;
            end
        end
        
        % Updates app visuals and interactivity given current checkbox and 
        % dropdown values
        function updateVisuals(app)
            simGoal = str2double(app.SimulationGoalDropDown.Value);

            % Update field & panel visibility based on simGoal
            app.LimitMaxAUVFleetSizeCheckBox.Visible = (simGoal == 1);  % 'Limit max AUV fleet size'
            app.LimitMaxAUVFleetSizeCheckBox.Enable = (simGoal == 1);
            app.LimitMaxAUVFleetSizeEditField.Visible = (simGoal == 1);
            app.DependentVariableDropDown.Visible = (simGoal == 1);  % 'Dependent variable dropdown'
            app.DependentVariableDropDown.Enable = (simGoal == 1);
            app.DependentVariableDropDownLabel.Visible = (simGoal == 1);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Visible = (simGoal == 1);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Enable = (simGoal == 1);

            % Update EditField ability from checkboxes 
            if app.LimitMaxAUVFleetSizeCheckBox.Value  % if box checked
                app.LimitMaxAUVFleetSizeEditField.Enable = true; 
            else
                app.LimitMaxAUVFleetSizeEditField.Enable = false;
                app.LimitMaxAUVFleetSizeEditField.Value = [];
            end
            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value  % if box checked
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = true;
            else
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = false;
                app.PredefinedCentralBatteryCapacityWhEditField.Value = [];
            end

            % Update 'Resource Data' panel interactivity
            switch app.ResourceDataTypeDropDown.Value
                case 0 % <none>
                    app.enableResourceDataPanel([]);
                case 1  % 'Sea State Power Data'
                    switch app.DependentVariableDropDown.Value
                        case 1  % 'AUV Model'
                            app.enableResourceDataPanel(app.ResourceDataSeaStatePanel);

                        case 2  % WEC Power Gen. / Wave Resource
                            app.enableResourceDataPanel([]);
                    end

                case 2  %'Power Gen. (Time Series)'
                    app.enableResourceDataPanel(app.ResourceDataPwrTimeSeriesUploadPanel);

                case 3  %'Power Gen. (Mean Value)'
                    app.enableResourceDataPanel(app.ResourceDataMeanPowerGenPanel);

                case 4  %'Wave Specs. (Time Series)'
                    app.enableResourceDataPanel(app.ResourceDataWaveTimeSeriesUploadPanel);

                case 5  %'Wave Specs. (Mean Value)'
                    app.enableResourceDataPanel(app.ResourceDataMeanWavePanel);

                case 6  %'Power Matrix with Time Series'
                    app.enableResourceDataPanel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            end  

            % Reset dropdown variable warnings
            k = keys(app.warnings);
            dropdownKeys = k(~strcmp(k, app.SimulationTimestepsEditField.Tag));
            for i = 1:numel(dropdownKeys)
                app.warnings(dropdownKeys{i}) = '';
            end
            app.refreshWarningDisplay();
        end
        
        function changeFieldTextColor(app, field, color) 
            arguments
                app  %#ok
                field
                color = [0, 0, 0]  % defaults to black if not provided
            end

            field.FontColor = color;
        end
        
        function enableResourceDataPanel(app, panel)
            % Disable and hide all resource data panels
            set(app.resourceDataPanels, 'Enable' , false);
            set(app.resourceDataPanels, 'Visible', false);

            % Reset app.dataMetadata property, file display, variable selection panels & csv checkbox (if applicable)
            app.resetDataMetadata();
            set(app.simSettingsvarSelectionPanels, 'Enable', false);
            set(app.simSettingsvarSelectionPanels, 'Visible', false);
            set(app.inputDataFileDisplays, 'Value', app.dataMetadata.fileName);
            set(app.allVarDropdowns, 'Items', app.dataMetadata.variables);
            if ~isequal(app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value, app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox)
                app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value = app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox;
            end

            % Reset generator losses checkbox
            app.ApplygeneratorefficiencylossesCheckBox_2.Value = app.Defaults.ApplygeneratorefficiencylossesCheckBox_2;
            app.ApplygeneratorefficiencylossesCheckBox_3.Value = app.Defaults.ApplygeneratorefficiencylossesCheckBox_3;

            % Enable given panel and its non-variable-selection children
            if ~isempty(panel)
                panel.Enable = true; 
                panel.Visible = true; 

                % ID given panel's variable selection panel
                varSelectionPanel = findall(panel, 'Tag', 'VariableSelectionPanel');

                % Enable all children except the variable selection panel & contents
                allChildren = findall(panel, '-property', 'Enable'); 
                varSelectChildren = findall(varSelectionPanel, '-property', 'Enable');
                enableChildren = allChildren(~ismember(allChildren, [varSelectionPanel; varSelectChildren]));
                set(enableChildren, 'Enable', true);
                set(enableChildren, 'Visible', true);

                % Keep variable selection panel visible and disabled until a successful browse
                if ~isempty(varSelectionPanel)
                    varSelectionPanel.Enable = false; 
                    varSelectionPanel.Visible = true;
                end

                % Reset browse success
                app.successfulBrowse = false;
            end
        end

        % Save default AUV model specs under the name 'models'
        function models = loadAUVModels(app) %#ok
            load('inputData/auvDefaultModels.mat', 'models');
            %{
            models = repmat(struct('name','' ,'mass', 0, 'batteryCapacity', 0, 'missionTime', 0, 'missionBatteryUsed', 0, 'hotelLoad', 0, 'chargeRate', 0, 'chargingMethod', 0, 'rechargeThreshold', 0, 'batteryEfficiency', 0), 21, 1);
            models(1).name = 'A';   models(1).mass = 27;     models(1).batteryCapacity = 800;     models(1).missionTime = 8*0.8;       models(1).missionBatteryUsed = 0.8;   models(1).hotelLoad = 90;                                         models(1).chargeRate = 160;          models(1).chargingMethod = 1;   models(1).rechargeThreshold = 0.2;  models(1).batteryEfficiency = 0.9;  
            models(2).name = 'B';   models(2).mass = 38.5;   models(2).batteryCapacity = 800;     models(2).missionTime = 14*0.8;      models(2).missionBatteryUsed = 0.8;   models(2).hotelLoad = 90;                                         models(2).chargeRate = 160;          models(2).chargingMethod = 1;   models(2).rechargeThreshold = 0.2;  models(2).batteryEfficiency = 0.9; 
            models(3).name = 'C';   models(3).mass = 38.6;   models(3).batteryCapacity = 1500;    models(3).missionTime = 10*0.8;      models(3).missionBatteryUsed = 0.8;   models(3).hotelLoad = 90;                                         models(3).chargeRate = 1500/6;       models(3).chargingMethod = 1;   models(3).rechargeThreshold = 0.2;  models(3).batteryEfficiency = 0.9;  
            models(4).name = 'D';   models(4).mass = 58.5;   models(4).batteryCapacity = 3000;    models(4).missionTime = 20*0.8;      models(4).missionBatteryUsed = 0.8;   models(4).hotelLoad = round(models(4).mass / 25);                 models(4).chargeRate = 3000/6;       models(4).chargingMethod = 1;   models(4).rechargeThreshold = 0.2;  models(4).batteryEfficiency = 0.9; 
            models(5).name = 'E';   models(5).mass = 70.3;   models(5).batteryCapacity = 4500;    models(5).missionTime = 30*0.8;      models(5).missionBatteryUsed = 0.8;   models(5).hotelLoad = round(models(5).mass / 25);                 models(5).chargeRate = 4500/6;       models(5).chargingMethod = 1;   models(5).rechargeThreshold = 0.2;  models(5).batteryEfficiency = 0.9; 
            models(6).name = 'F';   models(6).mass = 210;    models(6).batteryCapacity = 9600;    models(6).missionTime = 42*0.8;      models(6).missionBatteryUsed = 0.8;   models(6).hotelLoad = round(models(6).mass / 25);                 models(6).chargeRate = 9600/8;       models(6).chargingMethod = 1;   models(6).rechargeThreshold = 0.2;  models(6).batteryEfficiency = 0.9; 
            models(7).name = 'G';   models(7).mass = 279;    models(7).batteryCapacity = 19300;   models(7).missionTime = 80*0.8;      models(7).missionBatteryUsed = 0.8;   models(7).hotelLoad = round(models(7).mass / 25);                 models(7).chargeRate = 19300/10;     models(7).chargingMethod = 1;   models(7).rechargeThreshold = 0.2;  models(7).batteryEfficiency = 0.9; 
            models(8).name = 'H';   models(8).mass = 347;    models(8).batteryCapacity = 28900;   models(8).missionTime = 110*0.8;     models(8).missionBatteryUsed = 0.8;   models(8).hotelLoad = round(models(8).mass / 25);                 models(8).chargeRate = 28900/12;     models(8).chargingMethod = 1;   models(8).rechargeThreshold = 0.2;  models(8).batteryEfficiency = 0.9; 
            models(9).name = 'I';   models(9).mass = 1630;   models(9).batteryCapacity = 17550;   models(9).missionTime = 25*0.8;      models(9).missionBatteryUsed = 0.8;   models(9).hotelLoad = round(models(9).mass / 25);                 models(9).chargeRate = 17550/24;     models(9).chargingMethod = 1;   models(9).rechargeThreshold = 0.2;  models(9).batteryEfficiency = 0.9;  
            models(10).name = 'J';  models(10).mass = 70;    models(10).batteryCapacity = 1900;   models(10).missionTime = 8*0.8;      models(10).missionBatteryUsed = 0.8;  models(10).hotelLoad = round(models(10).mass / 25);               models(10).chargeRate = 1900/6;      models(10).chargingMethod = 1;  models(10).rechargeThreshold = 0.2;  models(10).batteryEfficiency = 0.9; 
            models(11).name = 'K';  models(11).mass = 250;   models(11).batteryCapacity = 7600;   models(11).missionTime = 36*0.8;     models(11).missionBatteryUsed = 0.8;  models(11).hotelLoad = round(models(11).mass / 25);               models(11).chargeRate = 7600/6;      models(11).chargingMethod = 1;  models(11).rechargeThreshold = 0.2;  models(11).batteryEfficiency = 0.9;
            models(12).name = 'L';  models(12).mass = 750;   models(12).batteryCapacity = 13500;  models(12).missionTime = 25*0.8;     models(12).missionBatteryUsed = 0.8;  models(12).hotelLoad = round(models(12).mass / 25);               models(12).chargeRate = 13500/6;     models(12).chargingMethod = 1;  models(12).rechargeThreshold = 0.2;  models(12).batteryEfficiency = 0.9; 
            models(13).name = 'M';  models(13).mass = 72.6;  models(13).batteryCapacity = 1500;   models(13).missionTime = 3.5*0.8;    models(13).missionBatteryUsed = 0.8;  models(13).hotelLoad = round(models(13).mass / 25);               models(13).chargeRate = 1500/6;      models(13).chargingMethod = 1;  models(13).rechargeThreshold = 0.2;  models(13).batteryEfficiency = 0.9;  
            models(14).name = 'N';  models(14).mass = 2200;  models(14).batteryCapacity = 62500;  models(14).missionTime = 72*0.8;     models(14).missionBatteryUsed = 0.8;  models(14).hotelLoad = round(models(14).mass / 25);               models(14).chargeRate = 62500/8;     models(14).chargingMethod = 1;  models(14).rechargeThreshold = 0.2;  models(14).batteryEfficiency = 0.9;
            models(15).name = 'O';  models(15).mass = 8000;  models(15).batteryCapacity = 400000; models(15).missionTime = 360*0.8;    models(15).missionBatteryUsed = 0.8;  models(15).hotelLoad = round(models(15).mass / 25);               models(15).chargeRate = 400000/8;    models(15).chargingMethod = 1;  models(15).rechargeThreshold = 0.2;  models(15).batteryEfficiency = 0.9;  
            models(16).name = 'P';  models(16).mass = 1000;  models(16).batteryCapacity = 24000;  models(16).missionTime = 24*0.8;     models(16).missionBatteryUsed = 0.8;  models(16).hotelLoad = round(models(16).mass / 25);               models(16).chargeRate = 24000/8;     models(16).chargingMethod = 1;  models(16).rechargeThreshold = 0.2;  models(16).batteryEfficiency = 0.9; 
            models(17).name = 'Q';  models(17).mass = 1550;  models(17).batteryCapacity = 48000;  models(17).missionTime = 74*0.8;     models(17).missionBatteryUsed = 0.8;  models(17).hotelLoad = round(models(17).mass / 25);               models(17).chargeRate = 48000/8;     models(17).chargingMethod = 1;  models(17).rechargeThreshold = 0.2;  models(17).batteryEfficiency = 0.9;  
            models(18).name = 'R';  models(18).mass = 25;    models(18).batteryCapacity = 600;    models(18).missionTime = 10*0.8;     models(18).missionBatteryUsed = 0.8;  models(18).hotelLoad = 1;                                         models(18).chargeRate = 600/5;       models(18).chargingMethod = 1;  models(18).rechargeThreshold = 0.2;  models(18).batteryEfficiency = 0.9;  
            models(19).name = 'S';  models(19).mass = 28;    models(19).batteryCapacity = 600;    models(19).missionTime = 10*0.8;     models(19).missionBatteryUsed = 0.8;  models(19).hotelLoad = round(models(19).mass / 25);               models(19).chargeRate = 600/4;       models(19).chargingMethod = 0;  models(19).rechargeThreshold = 0.2;  models(19).batteryEfficiency = 0.9; 
            models(20).name = 'T';  models(20).mass = 650;   models(20).batteryCapacity = 12000;  models(20).missionTime = 10.8*0.8;   models(20).missionBatteryUsed = 0.8;  models(20).hotelLoad = round(models(20).mass / 25);               models(20).chargeRate = 12000/3.64;  models(20).chargingMethod = 1;  models(20).rechargeThreshold = 0.2;  models(20).batteryEfficiency = 0.9;  
            models(21).name = 'U';  models(21).mass = 1300;  models(21).batteryCapacity = 30000;  models(21).missionTime = 21.6*0.8;   models(21).missionBatteryUsed = 0.8;  models(21).hotelLoad = round(models(21).mass / 25);               models(21).chargeRate = 30000/9.09;  models(21).chargingMethod = 1;  models(21).rechargeThreshold = 0.2;  models(21).batteryEfficiency = 0.9;  
            %}
        end
        
        % applyAUVModelSpecs fills AUV tab inputs with default values
        % paired with the selection of a default AUV (A-U). Will throw an
        % error if called when the chosen model is not a default value.
        function applyAUVModelSpecs(app)
            modelVal = str2double(app.ModelNameAUVDropdown.Value);
            
            app.MasskgEditField.Value = app.auvDefaultModels(modelVal).mass;
            app.BatteryCapacityWhAUVEditField.Value = app.auvDefaultModels(modelVal).batteryCapacity;
            app.MissionTimehrEditField.Value = app.auvDefaultModels(modelVal).missionTime;
            app.MissionBatteryUsed0XXEditField.Value = app.auvDefaultModels(modelVal).missionBatteryUsed;
            app.HotelLoadWAUVEditField.Value = app.auvDefaultModels(modelVal).hotelLoad;
            app.ChargeRateWEditField.Value = app.auvDefaultModels(modelVal).chargeRate;
            app.ChargingSwitch.Value = app.auvDefaultModels(modelVal).chargingMethod;  % 1: wired, 0: wireless
            app.BatteryRechargeThreshold0XXEditField.Value = app.auvDefaultModels(modelVal).rechargeThreshold;
            app.BatteryEfficiency0XXAUVEditField.Value = app.auvDefaultModels(modelVal).batteryEfficiency;

            % Set power transfer efficiency based on charging method
            app.getPowerTransferEfficiency();

            % Set all fields to gray text 
            auvFields = [app.ModelNameAUVDropdown, app.MasskgEditField, app.BatteryCapacityWhAUVEditField, app.MissionTimehrEditField, app.MissionBatteryUsed0XXEditField, app.HotelLoadWAUVEditField, app.ChargeRateWEditField, app.BatteryRechargeThreshold0XXEditField, app.BatteryEfficiency0XXAUVEditField, app.PowerTransferEfficiency0XXEditField];
            set(auvFields, 'FontColor', [0.5, 0.5, 0.5]);
        end
        
        % Prompts user for file selection, uploads data, and extracts
        % variable names. If multiselect is 'on', requires single file
        % selection. If allowCSV is enabled (true), browses for *.csv
        % filetypes, otherwise browses for *.mat files. Outputs
        % whether or not 'browse' was successful under
        % app.successfulBrowse, and data file name, path, and variables
        % under app.dataMetadata.
        function extractDataInfo(app, multiSelect, allowCSV) 
            arguments
                app
                multiSelect (1,1) string {mustBeMember(multiSelect, {'on', 'off'})} = 'off'  % optional, defaults to 'off'
                allowCSV = false  % optional, defaults to false
            end

            % Prompt user to browse for data file, extract file path
            if allowCSV
                [fileName, filePath] = uigetfile('*.csv', 'Select *.csv data file(s)', 'MultiSelect', multiSelect);
            else
                [fileName, filePath] = uigetfile('*.mat', 'Select *.mat data file(s)', 'MultiSelect', multiSelect);
            end

            % Track if browse was successful... 
            if ~filePath
                % Browse unsuccessful, do nothing.
                app.successfulBrowse = false;  
            else
                % Browse successful, save variable metadata and file path
                app.successfulBrowse = true; 

                if ~allowCSV
                    % Get variable metadata of *.mat file
                    if ischar(fileName)
                        fileName = {fileName};
                    end
                    fileContents = whos('-file', fullfile(filePath, fileName{1}));
        
                    % Save variable metadata to app
                    app.dataMetadata.variables = {fileContents.name};
                    app.dataMetadata.variableSizes = {fileContents.size};
                end

                % Save file name and path
                app.dataMetadata.path = filePath;
                app.dataMetadata.fileName = fileName;
            end
        end
        
        function resetDataMetadata(app)
            app.dataMetadata.variables = {};
            app.dataMetadata.variableSizes = {};
            app.dataMetadata.path      = [];
            app.dataMetadata.fileName  = '';
            app.successfulBrowse = false;  % If resetting metadata, reset browse checker to 0
        end
        
        % Evaluates the given variable's size against a given expectation,
        % or the default expected size of [nx1]. Displays a warning to the
        % GUI if variable is incorrectly sized.
        function warningMsg = evalVariableSize(app, variableName, variableSize, expectedRows, expectedCols)
            arguments
                app  %#ok
                variableName (1,1) string  % Name of variable
                variableSize (1,2) {mustBeNumeric, mustBePositive, mustBeInteger} % size of variable as a [1x2] numeric array
                expectedRows (1,1) {mustBeNumeric, mustBeNonnegative, mustBeInteger} = 0  % Expected variable rows, with 0 indicating any number.
                expectedCols (1,1) {mustBeNumeric, mustBeNonnegative, mustBeInteger} = 1  % Expected variable columns, with 0 indicating any number. 
            end
    
            % Read variable size
            rows = variableSize(1); 
            cols = variableSize(2); 
            
            % Compare variable size against expectation
            rowMatch = (expectedRows == 0) || (rows == expectedRows);
            colMatch = (expectedCols == 0) || (cols == expectedCols);

            rowMatchT = (expectedCols == 0) || (rows == expectedCols);
            colMatchT = (expectedRows == 0) || (cols == expectedRows); 

            inputIsVector = (rows == 1) || (cols == 1);
            matrixExpected = (expectedRows == 0) && (expectedCols == 0);
            matrixNotAVector = ~(matrixExpected && inputIsVector);  % Fail if matrix is expected, but vector is given

            validSize = ((rowMatch && colMatch) || (rowMatchT && colMatchT)) && matrixNotAVector; 

            if ~validSize
                if expectedRows == 0
                    rowText = 'm';
                else
                    rowText = num2str(expectedRows);
                end
                if expectedCols == 0
                    colText = 'n';
                else
                    colText = num2str(expectedCols);
                end

                warningMsg = sprintf('WARNING: %s is an unexpected size [%d x %d] (expecting [%s x %s]). This may cause computational error.', variableName, rows, cols, rowText, colText);
            else
                warningMsg = '';
            end            
        end
        
        % Displays active warnings. 
        function refreshWarningDisplay(app)
            activeWarnings = {};
            k = keys(app.warnings);
            for i = 1:numel(k)
                if ~isempty(app.warnings(k{i}))
                    activeWarnings{end+1} = app.warnings(k{i});
                end
            end

            if isempty(activeWarnings)
                app.WarningTextArea.Value = ''; 
            else
                app.WarningTextArea.Value = activeWarnings;
            end
        end
        
        % Checks resource data dropdown variable sizes and throws a warning if vectors selected are different sizes 
        function checkVectorLengths(app, parentPanel)
            % Get all variable selection dropdown values and sizes in the active panel
            varSelectionPanel = findall(parentPanel, 'Tag', 'VariableSelectionPanel');
            dropdowns = findall(varSelectionPanel, 'Type', 'uidropdown');
            dropdowns = dropdowns(~strcmp({dropdowns.Tag}, 'pwrMatrixVar'));  % Exclude power matrix variable from comparison (not a vector)

            % Get all variable lengths
            lengths = [];
            for i = 1:numel(dropdowns)
                varName = dropdowns(i).Value;
                if ~isempty(varName) 
                    varIndx = strcmp(app.dataMetadata.variables, varName);
                    if any(varIndx) 
                        varSize = app.dataMetadata.variableSizes{varIndx};
                        lengths(end+1) = max(varSize);
                    end
                end
            end

            % Check if lengths match, and trigger a warning if not
            if numel(unique(lengths)) > 1
                app.warnings('vectorLengthMismatch') = sprintf('WARNING: Selected variables have mismatched lengths [%s]. This may cause computational error.', num2str(unique(lengths)));
            else
                app.warnings('vectorLengthMismatch') = '';
            end
        end

        % Builds AUV class object from user-inputs
        function auv = compileAUV(app)
            auv = AUV(app.ModelNameAUVDropdown.Items{str2double(app.ModelNameAUVDropdown.Value)}, app.MasskgEditField.Value, ...
                app.BatteryCapacityWhAUVEditField.Value, app.MissionTimehrEditField.Value, ...
                app.MissionBatteryUsed0XXEditField.Value, app.HotelLoadWAUVEditField.Value, ...
                app.ChargeRateWEditField.Value, app.ChargingSwitch.Value, ...
                app.BatteryRechargeThreshold0XXEditField.Value, app.BatteryEfficiency0XXAUVEditField.Value, ...
                app.PowerTransferEfficiency0XXEditField.Value);
        end

        % Builds WEC class object from user-inputs
        function wec = compileWEC(app)
            activePanel = app.resourceDataPanels([app.resourceDataPanels.Visible] == true);
            genEfficiencyCheckbox = findall(activePanel, 'Type', 'uicheckbox', 'Tag', 'applyGenEfficiency');

            if  isempty(genEfficiencyCheckbox) || genEfficiencyCheckbox.Value
                % If checkbox does not exist, or exists and is 'checked', apply user-defined efficiency
                genEfficiency = app.GeneratorEfficiency0XXEditField.Value;
            else
                % If checkbox exists and is 'unchecked', do not apply efficiency value
                genEfficiency = 1.0; 
            end

            
            wec = WEC(app.ModelNameWECEditField.Value, app.CharacteristicDimensionmEditField.Value, ...
                app.BatteryCapacityWhWECEditField.Value, app.HotelLoadWWECEditField.Value, ...
                app.HydrodynamicEfficiency0XXEditField.Value, genEfficiency, ...
                app.BatteryEfficiency0XXWECEditField.Value);
        end

        % Builds EnergyStorage class object from user-inputs
        function energyStorage = compileEnergyStorage(app)
            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value
                batteryCapacity = app.PredefinedCentralBatteryCapacityWhEditField.Value; 
            else
                batteryCapacity = 0;  % Signals EnergyStorage to calculate its own size
            end

            energyStorage = EnergyStorage(batteryCapacity, app.BaseHotelLoadWEditField.Value, ...
                app.AUVDockHotelLoadWEditField.Value, ...
                app.BatteryEfficiency0XXESEditField.Value, app.WECPowerTransferEfficiency0XXEditField.Value, ...
                app.AUVDockPowerTransferEfficiency0XXEditField.Value, app.BatteryCRate0XXEditField.Value);
        end

        % Builds ModelInput class object from user-inputs
        function modIn = compileModelInput(app)
            simGoal = str2double(app.SimulationGoalDropDown.Value);
            switch simGoal
                case 1
                    depVar = app.DependentVariableDropDown.Items{app.DependentVariableDropDown.Value};
                case 2
                    depVar = "N/A";
            end
            resourceDataType = app.ResourceDataTypeDropDown.Value;
            outputPlots.pGen = app.PowerGenerationvsTimeCheckBox.Value; 
            outputPlots.centBatteryvtime = app.CentralBatteryLevelvsTimeCheckBox.Value;
            outputPlots.auvBatteryvtime = app.AUVBatteryLevelsvsTimeCheckBox.Value;
            outputPlots.aggWorkingHoursvDepVar = app.AggregateWorkingHoursvsDependentVariableCheckBox.Value;

            % Get all resource data variables
            switch app.ResourceDataTypeDropDown.Value
                case 0
                    resourceDataVars = struct();
                case 1  % Sea State
                    if app.DependentVariableDropDown.Value == 1
                        resourceDataVars.seaState = app.SelectSeaStateDropDown.Value;
                    else
                        resourceDataVars.seaState = [1, 2, 3, 4, 5, 6, 7, 8, 9, 10]; 
                    end

                case 2  % Power gen (time series)
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    resourceDataVars.tVarName = app.SelecttimevariableDropDown.Value;
                    resourceDataVars.pwrVarName = app.SelectpowervariableDropDown.Value;
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVars.dataFiles = dataFiles;

                case 3  % Power gen (mean value)
                    resourceDataVars.meanPower = app.parseEditFieldText(app.EntermeanpowergenerationWEditField.Value);

                case 4  % Wave specs (time series)
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVars.dataFiles = dataFiles;

                    % if *.csv checkbox is unchecked, save variable names
                    if ~app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value
                        resourceDataVars.HsVarName = app.SelectsignificantwaveheightvariableDropDown.Value;
                        resourceDataVars.TeVarName = app.SelectwaveenergyperiodvariableDropDown.Value;
                        resourceDataVars.TpVarName = app.SelectpeakperiodvariableDropDown.Value;
                        resourceDataVars.tVarName = app.SelecttimevariableDropDown_2.Value;
                    end

                case 5  % wave specs (mean value) 
                    resourceDataVars.sigWaveHeight = app.parseEditFieldText(app.EntermeansignificantwaveheightmEditField.Value);
                    resourceDataVars.energyPeriod = app.parseEditFieldText(app.EntermeanwaveenergyperiodsEditField.Value);
                    resourceDataVars.peakPeriod = resourceDataVars.energyPeriod;  % Simplifying user-input
                    % resourceDataVars.waveSpecTable = table(sigWaveHeight', energyPeriod', peakPeriod','VariableNames',{'SignificantWaveHeight','EnergyPeriod','PeakPeriod'});  % Current UI doesn't allow for vector inputs, so transpose doesn't do anything (yet)

                case 6  % power matrix w/ time series
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    resourceDataVars.pMatVarName = app.SelectpowervariablePwrMatDropDown.Value;
                    resourceDataVars.tVarName = app.SelecttimevariablePwrMatDropDown.Value; 
                    resourceDataVars.HsVarName = app.SelectsignificantwaveheightPwrMatDropDown.Value;
                    resourceDataVars.TeVarName = app.SelectwaveenergyperiodPwrMatDropDown.Value;
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVars.dataFiles = dataFiles;
            end

            auvModels = {app.auv.model};  % cell array of all saved model names

            if app.LimitMaxAUVFleetSizeCheckBox.Value
                maxFleetSize = app.LimitMaxAUVFleetSizeEditField.Value;
            else
                maxFleetSize = 0;
            end

            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value
                batteryCapacity = app.PredefinedCentralBatteryCapacityWhEditField.Value; 
            else
                batteryCapacity = 0;  % Signals EnergyStorage to calculate its own size
            end

            modIn = ModelInput(simGoal, app.SimulationDurationhrEditField.Value, depVar, ...
                resourceDataType, app.StaggerAUVDeploymentsCheckBox.Value, ...
                maxFleetSize, batteryCapacity, ...
                app.SimulationTimestepsEditField.Value, auvModels, resourceDataVars, outputPlots);
        end
        
        % Compiles all user-input settings into AUV, WEC, EnergyStorage,
        % and ModelInput class objects
        function [auv, wec, energyStorage, modelInput] = collectUIsettings(app)
            auv = app.auv; % AUV settings already compiled
            wec = app.compileWEC();
            energyStorage = app.compileEnergyStorage();
            modelInput = app.compileModelInput();
        end
        
        function editFieldNumericValues = parseEditFieldText(app, editFieldValue)
            % Returns numeric array from 'edit field' user-input text input
            components = strtrim(strsplit(editFieldValue, ','));
            editFieldNumericValues = str2double(components);
        end
        
        function setAppToDefaults(app)
            % Restores all GUI fields, internal variables, and GUI visuals
            % to their default startup values. Used in the GUI startup
            % function and via the 'Reset' button

            % ---- Simulation Settings panel ----
            app.SimulationGoalDropDown.Value = app.Defaults.SimulationGoalDropDown;
            app.SimulationDurationhrEditField.Value = app.Defaults.SimulationDurationhrEditField; 
            app.SimulationTimestepsEditField.Value = app.Defaults.SimulationTimestepsEditField; 
            app.StaggerAUVDeploymentsCheckBox.Value = app.Defaults.StaggerAUVDeploymentsCheckBox; 
            app.LimitMaxAUVFleetSizeCheckBox.Value = app.Defaults.LimitMaxAUVFleetSizeCheckBox;
            app.LimitMaxAUVFleetSizeEditField.Value = app.Defaults.LimitMaxAUVFleetSizeEditField;
            app.DependentVariableDropDown.Value = app.Defaults.DependentVariableDropDown;  %'AUV Model';
            app.ResourceDataTypeDropDown.Items = {'Sea State Power Data', 'Power Gen. (Time Series)', 'Power Gen. (Mean Value)', 'Wave Specs. (Time Series)', 'Wave Specs. (Mean Value)', 'Power Matrix with Time Series'};
            app.ResourceDataTypeDropDown.ItemsData = [1, 2, 3, 4, 5, 6];
            app.ResourceDataTypeDropDown.Value = app.Defaults.ResourceDataTypeDropDown;  %4-'Power Gen. (Time Series)'; 

            % ---- Resource Data Panels ----
            app.SelectSeaStateDropDown.Value = app.Defaults.SelectSeaStateDropDown;
            app.EntermeanwaveenergyperiodsEditField.Value = app.Defaults.EntermeanwaveenergyperiodsEditField;
            app.EntermeansignificantwaveheightmEditField.Value = app.Defaults.EntermeansignificantwaveheightmEditField;
            app.EntermeanpowergenerationWEditField.Value = app.Defaults.EntermeanpowergenerationWEditField;
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value = app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox;
            app.ApplygeneratorefficiencylossesCheckBox_2.Value = app.Defaults.ApplygeneratorefficiencylossesCheckBox_2;
            app.ApplygeneratorefficiencylossesCheckBox_3.Value = app.Defaults.ApplygeneratorefficiencylossesCheckBox_3;

            % Clear resource data variable selection dropdowns 
            dropdowns = [findall(app.VariableSelectionWaveData, 'Type', 'uidropdown'); findall(app.VariableSelectionPwrMatData, 'Type', 'uidropdown'); findall(app.VariableSelectionPwrData, 'Type', 'uidropdown')];
            if ~isempty(dropdowns)
                set(dropdowns, 'Items', {});
                set(dropdowns, 'Value', {});
            end

            % Clear displayed filenames 
            set(app.inputDataFileDisplays, 'Value', '');

            % ---- Hardware Specifications: WEC Tab ----
            app.ModelNameWECEditField.Value = app.Defaults.ModelNameWECEditField;
            app.CharacteristicDimensionmEditField.Value = app.Defaults.CharacteristicDimensionmEditField;
            app.HotelLoadWWECEditField.Value = app.Defaults.HotelLoadWWECEditField;
            app.BatteryCapacityWhWECEditField.Value = app.Defaults.BatteryCapacityWhWECEditField;
            app.HydrodynamicEfficiency0XXEditField.Value = app.Defaults.HydrodynamicEfficiency0XXEditField; 
            app.GeneratorEfficiency0XXEditField.Value = app.Defaults.GeneratorEfficiency0XXEditField;
            app.BatteryEfficiency0XXWECEditField.Value = app.Defaults.BatteryEfficiency0XXWECEditField;

            % ---- Hardware Specs: AUV Tab ----
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.savedAUVdisplay.Value = app.Defaults.savedAUVdisplay;
            app.applyAUVModelSpecs()  % call function that assigns AUV values
            app.auv = AUV.empty();  % clear any previously saved AUV configurations

            % ---- Hardware Specs: Energy Storage Tab ----
            app.PredefinedCentralBatteryCapacityWhCheckBox.Value = app.Defaults.PredefinedCentralBatteryCapacityWhCheckBox;
            app.PredefinedCentralBatteryCapacityWhEditField.Value = app.Defaults.PredefinedCentralBatteryCapacityWhEditField;
            app.BatteryEfficiency0XXESEditField.Value = app.Defaults.BatteryEfficiency0XXESEditField;
            app.WECPowerTransferEfficiency0XXEditField.Value = app.Defaults.WECPowerTransferEfficiency0XXEditField;
            app.AUVDockPowerTransferEfficiency0XXEditField.Value = app.Defaults.AUVDockPowerTransferEfficiency0XXEditField;
            app.BaseHotelLoadWEditField.Value = app.Defaults.BaseHotelLoadEditField; 
            app.AUVDockHotelLoadWEditField.Value = app.Defaults.AUVDockHotelLoadEditField; 
            app.BatteryCRate0XXEditField.Value = app.Defaults.BatteryCRate0XXEditField;

            % ---- Output plots panel ----
            app.PowerGenerationvsTimeCheckBox.Value = app.Defaults.PowerGenerationvsTimeCheckBox; 
            app.CentralBatteryLevelvsTimeCheckBox.Value = app.Defaults.CentralBatteryLevelvsTimeCheckBox;
            app.AUVBatteryLevelsvsTimeCheckBox.Value = app.Defaults.AUVBatteryLevelsvsTimeCheckBox; 
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Value = app.Defaults.AggregateWorkingHoursvsDependentVariableCheckBox;

            % ---- Reset metadata & warnings ----
            app.resetDataMetadata();  % clears dataMetadata struct and successfulBrowse flag
            k = keys(app.warnings);
            for i = 1:numel(k)
                app.warnings(k{i}) = '';
            end
            app.WarningTextArea.Value = '';

            % ---- Reset font colors ---- 
            allEditFields = [findall(app.UIFigure, 'Type','uieditfield'); findall(app.UIFigure, 'Type', 'uinumericeditfield'); findall(app.UIFigure, 'Type', 'uidropdown', 'Editable', true)];
            set(allEditFields, 'FontColor', [0.5, 0.5, 0.5]);

            % ---- Restore resource data panel visibility ----
            % % Disable & invisible resource data panel variable selection
            app.VariableSelectionWaveData.Enable = false; 
            app.VariableSelectionWaveData.Visible = false;
            app.VariableSelectionPwrMatData.Enable = false; 
            app.VariableSelectionPwrMatData.Visible = false;
            app.VariableSelectionPwrData.Enable = false; 
            app.VariableSelectionPwrData.Visible = false; 
            % Disable and hide all resource data panels
            set(app.resourceDataPanels, 'Enable' , false);
            set(app.resourceDataPanels, 'Visible', false);
            % Enable & visible startup resource data panel
            set(app.ResourceDataWaveTimeSeriesUploadPanel, 'Enable', true);
            set(app.ResourceDataWaveTimeSeriesUploadPanel, 'Visible', true);
            set(app.VariableSelectionWaveData, 'Visible', true);   

            % ---- Update panel visibility ----
            app.LimitMaxAUVFleetSizeCheckBox.Visible = (str2double(app.Defaults.SimulationGoalDropDown) == 1);  % 'Limit max AUV fleet size'
            app.LimitMaxAUVFleetSizeCheckBox.Enable = (str2double(app.Defaults.SimulationGoalDropDown) == 1);
            app.LimitMaxAUVFleetSizeEditField.Visible = (str2double(app.Defaults.SimulationGoalDropDown) == 1);
            app.DependentVariableDropDown.Visible = (str2double(app.Defaults.SimulationGoalDropDown) == 1);  % 'Dependent variable dropdown'
            app.DependentVariableDropDown.Enable = (str2double(app.Defaults.SimulationGoalDropDown) == 1);
            app.DependentVariableDropDownLabel.Visible = (str2double(app.Defaults.SimulationGoalDropDown) == 1);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Visible = (str2double(app.Defaults.SimulationGoalDropDown) == 1);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Enable = (str2double(app.Defaults.SimulationGoalDropDown) == 1);

             % Update EditField ability from checkboxes 
            if app.LimitMaxAUVFleetSizeCheckBox.Value  % if box checked
                app.LimitMaxAUVFleetSizeEditField.Enable = true; 
            else
                app.LimitMaxAUVFleetSizeEditField.Enable = false;
                app.LimitMaxAUVFleetSizeEditField.Value = [];
            end
            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value  % if box checked
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = true;
            else
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = false;
                app.PredefinedCentralBatteryCapacityWhEditField.Value = [];
            end

        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add subfolders to path
            addpath('Component Classes');
            addpath('Functions');
            addpath('InputData');

            % Set default AUV options
            app.auvDefaultModels = app.loadAUVModels(); 
            
            % Set default fields & switches
            % 'Simulation Settings' panel
            app.Defaults.SimulationGoalDropDown = '1';
            app.Defaults.SimulationDurationhrEditField = 14*24;
            app.Defaults.SimulationTimestepsEditField = 30; 
            app.Defaults.StaggerAUVDeploymentsCheckBox = true; 
            app.Defaults.LimitMaxAUVFleetSizeCheckBox = false;
            app.Defaults.LimitMaxAUVFleetSizeEditField = [];
            app.Defaults.DependentVariableDropDown = 1;  % 'AUV Model';
            app.Defaults.ResourceDataTypeDropDown = 4;  % 4-'Wave Specs (Time Series)'; 
            % Resource Data panels
            app.Defaults.SelectSeaStateDropDown = '3';
            app.Defaults.EntermeanwaveenergyperiodsEditField = '1.5';
            app.Defaults.EntermeansignificantwaveheightmEditField = '2';
            app.Defaults.EntermeanpowergenerationWEditField = '1200';
            app.Defaults.EntermeanwaveenergyperiodsEditField_multInputs = '1.5, 1.75, 2.0';
            app.Defaults.EntermeansignificantwaveheightmEditField_multInputs = '2, 2.25, 2.5';
            app.Defaults.EntermeanpowergenerationWEditField_multInputs = '1200, 1400, 1600';
            app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox = false;
            app.Defaults.ApplygeneratorefficiencylossesCheckBox_2 = true;
            app.Defaults.ApplygeneratorefficiencylossesCheckBox_3 = true;
            % 'Hardware Specifications' panel
            % WEC Tab
            app.Defaults.ModelNameWECEditField = 'Default';
            app.Defaults.CharacteristicDimensionmEditField = 1;
            app.Defaults.HotelLoadWWECEditField = 5;
            app.Defaults.BatteryCapacityWhWECEditField = 500;
            app.Defaults.HydrodynamicEfficiency0XXEditField = (1.3 * app.Defaults.CharacteristicDimensionmEditField + 5.6)/100; 
            app.Defaults.GeneratorEfficiency0XXEditField = 0.8;
            app.Defaults.BatteryEfficiency0XXWECEditField = 0.9;
            % AUV Tab
            app.Defaults.ModelNameAUVDropdown = '1';
            app.Defaults.savedAUVdisplay = {''};
            % Energy Storage Tab
            app.Defaults.PredefinedCentralBatteryCapacityWhCheckBox = false;
            app.Defaults.PredefinedCentralBatteryCapacityWhEditField = [];
            app.Defaults.BatteryEfficiency0XXESEditField = 0.9;
            app.Defaults.WECPowerTransferEfficiency0XXEditField = 0.9;
            app.Defaults.AUVDockPowerTransferEfficiency0XXEditField = 0.9;
            app.Defaults.BaseHotelLoadEditField = 10;
            app.Defaults.AUVDockHotelLoadEditField = 10;
            app.Defaults.BatteryCRate0XXEditField = 0.2; 
            % 'Output Plots' panel
            app.Defaults.PowerGenerationvsTimeCheckBox = false; 
            app.Defaults.CentralBatteryLevelvsTimeCheckBox = false;
            app.Defaults.AUVBatteryLevelsvsTimeCheckBox = false; 
            app.Defaults.AggregateWorkingHoursvsDependentVariableCheckBox = false;

            % Generate 'warnings' container that maps to component tag 
            app.warnings = containers.Map('KeyType', 'char', 'ValueType', 'char');
            % Initialize variable dropdown and simulation timestep warnings as empty strings
            searchRoots = [app.SimulationSettingsPanel, app.HardwareSpecsPanel];  % No warnings in the 'output plots' panel, don't need to look there
            taggedComponents = findall(searchRoots, '-not', 'Tag', '');  
            for i = 1:numel(taggedComponents)
                tag = taggedComponents(i).Tag;
                % Exclude panel / display tags
                if ~ismember(tag, {'resourceDataPanel', 'VariableSelectionPanel', 'dataFileDisplay'})
                    app.warnings(tag) = '';
                end
            end
            % Initialize other warnings as empty strings
            app.warnings('vectorLengthMismatch') = '';

            % Compile panels / displays / dropdowns
            app.resourceDataPanels = findall(app.SimulationSettingsPanel, 'Tag', 'resourceDataPanel');
            app.simSettingsvarSelectionPanels = findall(app.SimulationSettingsPanel, 'Tag', 'VariableSelectionPanel');
            app.inputDataFileDisplays = findall(app.SimulationSettingsPanel, 'Tag', 'dataFileDisplay');
            app.allVarDropdowns = findall(app.simSettingsvarSelectionPanels, '-property', 'Items');

            % Set app to defaults, initialize auv object, set fields to gray
            app.setAppToDefaults();
            
        end

        % Value changed function: AUVDockHotelLoadWEditField, 
        % ...and 23 other components
        function editFieldChanged(app, event)
            app.changeFieldTextColor(event.Source);
        end

        % Value changed function: LimitMaxAUVFleetSizeCheckBox, 
        % ...and 2 other components
        function AppModifyingValueChanged(app, event)
            app.updateVisuals();
        end

        % Value changed function: ChargingSwitch
        function ChargingSwitchValueChanged(app, event)
            % Assess charging switch value & determine respective power
            % transfer efficiency
            app.getPowerTransferEfficiency();
            app.changeFieldTextColor(app.PowerTransferEfficiency0XXEditField, [0.5, 0.5, 0.5]);
        end

        % Value changed function: ModelNameAUVDropdown
        function ModelNameAUVDropdownValueChanged(app, event)
            value = app.ModelNameAUVDropdown.Value;
            
            % If model is included in the preset dropdown list, keep gray
            % text, otherwise change text color to black.
            if ismember(value, event.Source.ItemsData)
                app.applyAUVModelSpecs();
            else
                app.changeFieldTextColor(event.Source);  % black for user-entry
            end
        end

        % Button pushed function: BrowsePwrButton, BrowsePwrMatButton, 
        % ...and 1 other component
        function BrowseButtonPushed(app, event)
            simGoal = str2double(app.SimulationGoalDropDown.Value);
            % Get file upload settings
            if (simGoal == 2) || app.DependentVariableDropDown.Value == 1
                multiSelect = 'off'; 
            else
                multiSelect = 'on';
            end

            allowCSV = app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value;
            
            % Get data file path(s) and variable names (if *.mat)
            app.extractDataInfo(multiSelect, allowCSV);

            % If successful browse, display file, & (if *.mat) enable variable selection dropdowns 
            if app.successfulBrowse
                % Source Browse button's parent panel
                parentPanel = event.Source.Parent;

                % Display file selected
                dataFileDisplayText = findall(parentPanel, 'Type', 'uitextarea');
                set(dataFileDisplayText, 'Value', app.dataMetadata.fileName)

                if ~allowCSV
                    % Source variable selection panel to enable
                    varSelectionPanel = findall(parentPanel, 'Tag', 'VariableSelectionPanel');
                    if ~isempty(varSelectionPanel)
                        % Enable panel and dropdowns
                        varSelectionPanel.Enable = true;
                        varSelectionPanel.Visible = true;
                        dropdowns = findall(varSelectionPanel, 'Type', 'uidropdown'); 
                        set(dropdowns, 'Enable', true);
    
                        % Populate dropdowns with variable names
                        set(dropdowns, 'Items', app.dataMetadata.variables); 
                    end
                end
                
            end

            % Bring app to front
            figure(app.UIFigure);
        end

        % Value changed function: 
        % DataisacsvtablecontainingtherequiredheadersCheckBox
        function csvCheckBoxValueChanged(app, event)
            % Note: the csv checkbox only exists on the 'wave time series' resource data panel, so only that panel's properties need to be reset here    
            % Clear app.dataMetadata property & reset 'successful browse'
            app.resetDataMetadata();

            % Set variable dropdown selections to empty & Disable
            dropdowns = findall(app.VariableSelectionWaveData, 'Type', 'uidropdown'); 
            set(dropdowns, 'Items', app.dataMetadata.variables);
            app.VariableSelectionWaveData.Enable = false; 

            % Clear displayed data file
            app.DatafileWaveTextArea.Value = ''; 
        
        end

        % Value changed function: SelectpeakperiodvariableDropDown, 
        % ...and 8 other components
        function VectorVariableDropDownValueChanged(app, event)
            % Get dropdown clicked and its value
            parentDropdown = event.Source;
            varName = parentDropdown.Value; 

            % Get actual variable size
            varIndx = strcmp(app.dataMetadata.variables, varName);
            varSize = app.dataMetadata.variableSizes{varIndx};

            % Check variable size against expected
            app.warnings(parentDropdown.Tag) = app.evalVariableSize(varName, varSize, 0, 1);

            % Check variable lengths against other variables
            parentPanel = event.Source.Parent;
            app.checkVectorLengths(parentPanel); 

            % Refresh warning display
            app.refreshWarningDisplay();
        end

        % Value changed function: SelectpowervariablePwrMatDropDown
        function MatrixVariableDropDownValueChanged(app, event)
            parentDropdown = event.Source; 
            varName = parentDropdown.Value;
            
            % Get actual variable size
            varIndx = strcmp(app.dataMetadata.variables, varName);
            varSize = app.dataMetadata.variableSizes{varIndx};

            % Check variable size against expected
            app.warnings(parentDropdown.Tag) = app.evalVariableSize(varName, varSize, 0, 0);

            % Refresh warning display
            app.refreshWarningDisplay();
        end

        % Value changed function: SimulationTimestepsEditField
        function SimulationTimestepsEditFieldValueChanged(app, event)
            value = app.SimulationTimestepsEditField.Value;
            
            % Update edited text color to black
            app.changeFieldTextColor(event.Source);

            % Display warning if timestep is large
            if value > 300
                app.warnings(app.SimulationTimestepsEditField.Tag) = sprintf('WARNING: Simulation timestep of %d s is large and may cause fidelity issues.', value);
            else
                app.warnings(app.SimulationTimestepsEditField.Tag) = '';
            end
            app.refreshWarningDisplay();
        end

        % Value changed function: DependentVariableDropDown
        function DependentVariableDropDownValueChanged(app, event)
            % Reset Resource Data Tab
            app.updateVisuals();

            % Reset mean user-input values to default & update text color
            depVar = event.Value; 
            if depVar == 1
                app.EntermeanwaveenergyperiodsEditField.Value = app.Defaults.EntermeanwaveenergyperiodsEditField;
                app.EntermeansignificantwaveheightmEditField.Value = app.Defaults.EntermeansignificantwaveheightmEditField;
                app.EntermeanpowergenerationWEditField.Value = app.Defaults.EntermeanpowergenerationWEditField;
            else
                app.EntermeanwaveenergyperiodsEditField.Value = app.Defaults.EntermeanwaveenergyperiodsEditField_multInputs;
                app.EntermeansignificantwaveheightmEditField.Value = app.Defaults.EntermeansignificantwaveheightmEditField_multInputs;
                app.EntermeanpowergenerationWEditField.Value = app.Defaults.EntermeanpowergenerationWEditField_multInputs;  
            end

            app.changeFieldTextColor(app.EntermeanwaveenergyperiodsEditField, [0.5, 0.5, 0.5]);
            app.changeFieldTextColor(app.EntermeansignificantwaveheightmEditField, [0.5, 0.5, 0.5]);
            app.changeFieldTextColor(app.EntermeanpowergenerationWEditField, [0.5, 0.5, 0.5]);

            % Reset AUV Tab
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.applyAUVModelSpecs();
            app.savedAUVdisplay.Value = app.Defaults.savedAUVdisplay;

            % Reset app.auv object
            app.auv = AUV.empty(); 
        end

        % Button pushed function: AddAUVButton
        function AddAUVButtonPushed(app, event)
            % Displays 'saved' AUV in sidebar, saves AUV settings to an AUV
            % class object (app.auv), and resets AUV panel to default
            % settings. Allows multiple configured AUVs if Dependent
            % Variable == AUV Model, otherwise only allows one 'configured
            % AUV'
            % Get AUV model name
            auvModel = app.ModelNameAUVDropdown.Value;
            if ismember(auvModel, app.ModelNameAUVDropdown.ItemsData)
                % Preset model use display name in Items list
                displayName = app.ModelNameAUVDropdown.Items{str2double(auvModel)};
            else
                % Custom model
                displayName = auvModel;
            end

            % Display AUV model name in sidebar 
            
            % Get current configured AUV display
            currentDisplay = app.savedAUVdisplay.Value;

            % Display and save AUV
            simGoal = str2double(app.SimulationGoalDropDown.Value);
            overwriteAUV = (simGoal == 1) && (app.DependentVariableDropDown.Value == 2); 

            % If first AUV saved, or configuring only one AUV, overwrites prior-saved AUV
            if isempty(currentDisplay) || all(cellfun(@(x) isempty(strtrim(x)), currentDisplay)) || overwriteAUV
                app.savedAUVdisplay.Value = {displayName};
            else
                % If configuring multiple AUVs, appends the 'n'th AUV...
                app.savedAUVdisplay.Value = [currentDisplay; {displayName}];
            end

            % Saves auv settings to auv class object (property of app...)
            % if 'n'th auv, appends object as 'n'th item of auv array...
            if isempty(app.auv) || overwriteAUV
                app.auv = app.compileAUV();
            else
                app.auv(end+1) = app.compileAUV();
            end

            % Resets AUV tab values to default
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.applyAUVModelSpecs();
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            simGoal = str2double(app.SimulationGoalDropDown.Value);
            % Check that all inputs have been given
            
            % Checkbox user-inputs filled in (if selected)
            maxFleetInputGiven = (simGoal == 2) || (app.LimitMaxAUVFleetSizeCheckBox.Value == 0) || ((app.LimitMaxAUVFleetSizeCheckBox.Value == 1) && (~isempty(app.LimitMaxAUVFleetSizeEditField.Value)));
            centBatteryGiven = (app.PredefinedCentralBatteryCapacityWhCheckBox.Value == 0) || ((app.PredefinedCentralBatteryCapacityWhCheckBox.Value == 1) && (~isempty(app.PredefinedCentralBatteryCapacityWhEditField.Value)));
            
            % Resource data uploaded, or not needed
            dataFileGiven = app.successfulBrowse || ismember(app.ResourceDataTypeDropDown.Value, [0,1,3,5]) || (simGoal == 2); 
            
            % Variable selections are unique
            varSelectionsUnique = true; 
            if ismember(app.ResourceDataTypeDropDown.Value, [2,4,6])  % if resource data requires upload
                activePanel = app.resourceDataPanels([app.resourceDataPanels.Visible] == true);
                if ~isempty(activePanel) && ~app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value  % If there is an active panel, and the data is not a *.csv file
                    varSelectionPanel = findall(activePanel, 'Tag', 'VariableSelectionPanel');
                    if ~isempty(varSelectionPanel) && varSelectionPanel.Enable  % If there is an enabled variable selection panel
                        dropdowns = findall(varSelectionPanel, 'Type', 'uidropdown');
                        selections = {dropdowns.Value};
                        varSelectionsUnique = numel(unique(selections)) >= 2;  % allows peak period variable == wave energy period variable if desired
                    end
                end
            end
            
            % At least one AUV configuration saved
            auvConfigured = ~isempty(app.auv);

            if maxFleetInputGiven && centBatteryGiven && dataFileGiven && varSelectionsUnique && auvConfigured
                % Disable 'run simulation' button & display progress bar..
                app.RunSimulationButton.Enable = false;
                d = uiprogressdlg(app.UIFigure, 'Title', 'Running Simulation...', 'Indeterminate', 'on');
    
                try
                    % Get hardware objects (WEC, AUV, CentralBattery) from settings
                    [app.auv, wec, energyStorage, modIn] = app.collectUIsettings();

                    % Evaluate c-rate compatibility if user provides a
                    % central battery capacity under worst-case conditions
                    % (no power generation & all AUVs docked)
                    cRateGood = true;  % init. c-rate flag
                    if ~isempty(app.PredefinedCentralBatteryCapacityWhEditField.Value)
                        switch simGoal
                            case 1
                                % ID auv w/ largest charge-rate:
                                [~, auvMaxRateIndx] = max([app.auv.chargeRate]);
                                [maxHotelLoad, ~] = evalSystLoads(modIn, app.auv(auvMaxRateIndx), 1, energyStorage);  % Assume fleet size of 1
                            case 2
                                [maxHotelLoad, ~] = evalSystLoads(modIn, app.auv, numel(app.auv), energyStorage);
                        end

                        % Prompt user-input if draw > limit from c-rate
                        if maxHotelLoad > (app.BatteryCRate0XXEditField.Value * app.PredefinedCentralBatteryCapacityWhEditField.Value)
                            dlgTxt = "The energy storage battery c-rate is incompatible with the system's hotel load. Continue simulation without applying c-rate limits, or return to GUI?";
                            cRateAns = questdlg(dlgTxt, 'C-Rate Incompatibility', 'Continue without c-rate', 'Return to GUI', 'Continue without c-rate');
                            
                            switch cRateAns
                                case 'Continue without c-rate'
                                    % Set c-rate to '0' (no-limit)
                                    energyStorage.cRate = 0;
                                    cRateGood = true;
        
                                case 'Return to GUI'
                                    cRateGood = false;
                            end
                        end
                    end
        
                    if cRateGood  % continue w/ simulation. Otherwise, halt and return to GUI
                        % Generate modelOutput object
                        modOut = ModelOutput(modIn.simTime);
    
                        % Initialize results temp. object
                        simResults = SimResults();
                            
                        % Determine dependent variable for-loop length & calc.
                        % power required as applicable
                        switch modIn.simGoal
                            case 1  % Input power gen, output AUV fleet size
                                switch modIn.depVar
                                    case 'AUV Model'
                                        loopLength = length(modIn.auvModels); 
                                    case 'WEC Power Gen. / Wave Resource' 
                                        switch modIn.resourceDataType
                                            case 1
                                                loopLength = 10;  %length(RM3); 
                                            case 2
                                                loopLength = length(modIn.resourceDataVars.dataFiles);
                                            case 3
                                                loopLength = length(modIn.resourceDataVars.meanPower);
                                            case 4
                                                loopLength = length(modIn.resourceDataVars.dataFiles);
                                            case 5
                                                loopLength = length(modIn.resourceDataVars.sigWaveHeight);
                                            case 6
                                                loopLength = length(modIn.resourceDataVars.dataFiles);
                                        end
                                end
    
                            case 2  % input AUV fleet, output pwr required
                                loopLength = 1;
    
                                % Calculate power required 
                                minPowerRequired = evalPwrRequired(app.auv, wec, energyStorage);
                                modIn.addResourceDataVar('minPowerRequired', minPowerRequired);
                        end
            
                        % Calculate power Gen. & save before loop if using the same
                        % generation each time
                        if (modIn.simGoal == 2) || strcmp(modIn.depVar, 'AUV Model')
                            modIn.calcPowerGen(wec, simResults)  
                        end
        
                        % Run simulation for each set of input properties
                        for simCount = 1:loopLength
        
                            % Reset hardware object battery time-series (power-gen preserved)
                            wec.reset();  
                            energyStorage.reset();
                            arrayfun(@(auv) auv.reset(), app.auv); 
    
        
                            % If switching resource data each simulation, need to
                            % re-calculate power gen & reset auv object before
                            % running simulation calcs
                            switch modIn.simGoal
                                case 1  % 
                                    switch modIn.depVar
                                        case 'AUV Model'
                                            runConfig(app.auv(simCount), wec, energyStorage, modIn, simResults);
    
                                        case 'WEC Power Gen. / Wave Resource'
                                            modIn.calcPowerGen(wec, simResults, simCount);
        
                                            runConfig(app.auv, wec, energyStorage, modIn, simResults);
    
                                    end
                               
                                case 2  % input AUV fleet, output power
                                    runConfig(app.auv, wec, energyStorage, modIn, simResults);
    
                            end
                            
                            % save results to modOut object
                            modOut.fleetSize(simCount) = simResults.fleetSize;
                            modOut.energyStorageBatteryLvl(:, simCount) = simResults.energyStorageBatteryLvl;
                            modOut.wecBatteryLvl{simCount} = simResults.wecBatteryLvl; 
                            modOut.auvBatteryLvl{simCount} = simResults.auvBatteryLvl;
                            modOut.auvSchedule{simCount} = simResults.auvSchedule; 
                            modOut.auvTimeOnMission{simCount} = simResults.auvTimeOnMission;
                            modOut.auvTimeOnMissionCorrected{simCount} = simResults.auvTimeOnMissionCorrected; 
                            modOut.auvFleet{simCount} = simResults.auvFleet; 
                            modOut.centralBatteryCapacity(simCount) = simResults.centralBatteryCapacity;
                            modOut.meanPowerGen(simCount) = simResults.meanPowerGen;
                            modOut.numWECs = simResults.numWECs;
                            if (simCount == 1) || ((modIn.simGoal == 1) && strcmp(modIn.depVar, 'WEC Power Gen. / Wave Resource'))
                                modOut.powerGenMeans(:,simCount) = simResults.powerGenMeans; 
                            end
                        end
        
                        % Save results in 'outputData' folder
                        % generate filename using input data filename
                        switch modIn.simGoal
                            case 1  % 
                                auv = app.auv;  %#ok
                                switch modIn.depVar
                                    case 'AUV Model'
                                        outputFilename = append("outputData/", extractBefore(app.dataMetadata.fileName, "."), "_results_", char(datetime('now', 'Format', 'ddMMMyy_HHmm')), ".mat");
                                    case 'WEC Power Gen. / Wave Resource'
                                        outputFilename = append("outputData/varyingPowerGen_results_", char(datetime('now', 'Format', 'ddMMMyy_HHmm')), ".mat");
                                end
    
                            case 2
                                auv = simResults.auvFleet; %#ok
                                outputFilename = append("outputData/knownAUVfleet_results_", char(datetime('now', 'Format', 'ddMMMyy_HHmm')), ".mat");
                                
                        end
                        save(outputFilename, 'auv', 'wec', 'energyStorage', 'modOut', 'modIn');
    
                               
                        % plot / display results
                        plotPwr = app.PowerGenerationvsTimeCheckBox.Value;
                        plotCentralBattery = app.CentralBatteryLevelvsTimeCheckBox.Value;
                        plotAUVbattery = app.AUVBatteryLevelsvsTimeCheckBox.Value;
                        if modIn.simGoal == 1
                            plotWorkingHours = app.AggregateWorkingHoursvsDependentVariableCheckBox.Value;
                        else
                            plotWorkingHours = false;
                        end
        
                        % If all power tracking checkboxes are selected, plot them in
                        % the same window
                        if plotPwr && plotCentralBattery && plotAUVbattery
                            modOut.plotPowerTrackerPanel(modIn);
            
                        else
                            if plotPwr
                                modOut.plotPowerGen();
                            end
                            
                            if plotCentralBattery
                                modOut.plotCentralBatteryTracker(modIn);
                            end
            
                            if plotAUVbattery
                                modOut.plotAUVbatteryTracker(modIn);
                            end
                        end
            
                        % Global statistic plot
                        if plotWorkingHours
                            modOut.plotWorkingHours(modIn);
                        end
                    end

                catch e
                    % Display error details
                    uialert(app.UIFigure, e.message, 'Simulation Error');
    
                    % Print full stack trace to command window for debug.
                    fprintf('\nSimulation Error: %s\n', e.message);
                    for i = 1:numel(e.stack)
                        fprintf(' in %s (line %d) \n', e.stack(i).name, e.stack(i).line);
                    end
    
                end
    
                % Re-enable button, end progress bar, bring GUI to front
                close(d); 
                figure(app.UIFigure);
                app.RunSimulationButton.Enable = true;

            else
                % Display warning based on check that failed
                alertMessages = {};
                if ~maxFleetInputGiven
                    alertMessages{end+1} = 'Please enter a maximum AUV fleet size, or uncheck "Limit Max AUV Fleet Size".';
                end
                if ~centBatteryGiven
                    alertMessages{end+1} = 'Please enter a central battery capacity, or uncheck "Predefined Central Battery Capacity".';
                end
                if ~dataFileGiven 
                    alertMessages{end+1} = 'Please upload a resource data file.';
                end
                if ~varSelectionsUnique
                    alertMessages{end+1} = 'Please configure the resource variable names.';
                end
                if ~auvConfigured
                    alertMessages{end+1} = 'Please configure and save at least one AUV.';
                end

                if ~isempty(alertMessages)
                    uialert(app.UIFigure, strjoin(alertMessages, newline), 'Missing or Invalid Inputs');
                end
            end
        end

        % Value changed function: CharacteristicDimensionmEditField
        function CharacteristicDimensionmEditFieldValueChanged(app, event)
            charDim = app.CharacteristicDimensionmEditField.Value;
            app.changeFieldTextColor(event.Source);
            app.HydrodynamicEfficiency0XXEditField.Value = (1.3 * charDim + 5.6)/100;
        end

        % Value changed function: EntermeanpowergenerationWEditField, 
        % ...and 2 other components
        function ResourceDataEditFieldValueChanged(app, event)
            editFieldValue = event.Value;
            
            % Change color to black 
            app.changeFieldTextColor(event.Source);

            % Extract numeric values from edit field input
            editFieldNumericValues = app.parseEditFieldText(editFieldValue);

            warningText = ''; 

            % Check if input is numeric
            if any(isnan(editFieldNumericValues))
                warningText = 'Resource data input is not numeric and will cause computational error.';
            end

            % Check if input is scalar / vector
            if ~isscalar(editFieldNumericValues) && ( (simGoal == 1 && app.DependentVariableDropDown.Value == 1) || simGoal == 2 )
                % user-input should be scalar but is a vector
                if isempty(warningText)
                    warningText = 'WARNING: Expecting scalar resource data variable.'; 
                else
                    warningText = strcat(warningText, '\nWARNING: Expecting scalar resource data variable.');
                end
            end

            app.warnings(event.Source.Tag) = sprintf(warningText); 

            % Check if same size as other edit fields in same panel
            % (error code: vectorLengthMismatch)
            parentPanel = event.Source.Parent;
            siblingFields = findall(parentPanel, 'Type', 'uieditfield');

            siblingFieldSizes = []; 
            for i = 1:numel(siblingFields)
                siblingFieldValue = app.parseEditFieldText(siblingFields(i).Value); 
                siblingFieldSizes(i) = numel(siblingFieldValue);
            end
            
            % Apply appropriate warning/error text
            if numel(unique(siblingFieldSizes)) > 1
                app.warnings('vectorLengthMismatch') = sprintf('WARNING: Provided variables have mismatched lengths [%s]. This may cause computational error.', num2str(unique(siblingFieldSizes)));
            else
                app.warnings('vectorLengthMismatch') = '';
            end

            % Refresh warning display
            app.refreshWarningDisplay();
        end

        % Value changed function: SimulationGoalDropDown
        function SimulationGoalDropDownValueChanged(app, event)
            simGoal = str2double(app.SimulationGoalDropDown.Value);

            % Reset values based on goal chosen
            switch simGoal
                case 1
                    app.ResourceDataTypeDropDown.Items = {'Sea State Power Data', 'Power Gen. (Time Series)', 'Power Gen. (Mean Value)', 'Wave Specs. (Time Series)', 'Wave Specs. (Mean Value)', 'Power Matrix with Time Series'};
                    app.ResourceDataTypeDropDown.ItemsData = [1, 2, 3, 4, 5, 6];
                    app.ResourceDataTypeDropDown.Value = 4;
                case 2
                    app.ResourceDataTypeDropDown.Items = {'<none>', 'Wave Specs. (Time Series)', 'Wave Specs. (Mean Value)'};
                    app.ResourceDataTypeDropDown.ItemsData = [0, 4, 5];
                    app.ResourceDataTypeDropDown.Value = 0;
                    app.LimitMaxAUVFleetSizeCheckBox.Value = false;
            end
            
            % Update panel visibility
            app.updateVisuals;
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
            % calls 'set app to defaults' function
            app.setAppToDefaults();

            % Reset Run Simulation button
            if app.RunSimulationButton.Enable == false
                app.RunSimulationButton.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayoutMain = uigridlayout(app.UIFigure);
            app.OutputPlotsPanel = uipanel(app.GridLayoutMain);
            app.OutputPlotsPanel.Title = 'Output Plots';
            app.GridLayoutOutputPlots = uigridlayout(app.OutputPlotsPanel);
            app.PowerGenerationvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.PowerGenerationvsTimeCheckBox.Tooltip = {'Plot power generation as a function of simulation time. '};
            app.PowerGenerationvsTimeCheckBox.Text = {'Power Generation '; 'vs. Time'};
            app.CentralBatteryLevelvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.CentralBatteryLevelvsTimeCheckBox.Tooltip = {'Plot central battery level as a function of simulation time.'};
            app.CentralBatteryLevelvsTimeCheckBox.Text = {'Central Battery Level '; 'vs. Time'};
            app.AUVBatteryLevelsvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.AUVBatteryLevelsvsTimeCheckBox.Tooltip = {'Plot AUV battery level(s) as a function of simulation time.'};
            app.AUVBatteryLevelsvsTimeCheckBox.Text = {'AUV Battery Level(s)'; 'vs. Time'};
            app.AggregateWorkingHoursvsDependentVariableCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Tooltip = {'Plot aggregate AUV working hours percentage as a function of the dependent variable.'};
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Text = {'Aggregate Working Hours '; '% vs. Dependent Variable'};
            app.HardwareSpecsPanel = uipanel(app.GridLayoutMain);
            app.HardwareSpecsPanel.Title = 'Hardware Specifications';
            app.TabGroup = uitabgroup(app.HardwareSpecsPanel);
            app.WECTab = uitab(app.TabGroup);
            app.WECTab.Title = 'WEC';
            app.ModelNameEditFieldLabel = uilabel(app.WECTab);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameWECEditField = uieditfield(app.WECTab, 'text');
            app.ModelNameWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.ModelNameWECEditField.Tooltip = {'Name of WEC model'};
            app.ModelNameWECEditField.Value = 'Default';
            app.CharacteristicDimensionmEditFieldLabel = uilabel(app.WECTab);
            app.CharacteristicDimensionmEditFieldLabel.Text = 'Characteristic Dimension [m]:';
            app.CharacteristicDimensionmEditField = uieditfield(app.WECTab, 'numeric');
            app.CharacteristicDimensionmEditField.LowerLimitInclusive = 'off';
            app.CharacteristicDimensionmEditField.Limits = [0 1000];
            app.CharacteristicDimensionmEditField.ValueChangedFcn = createCallbackFcn(app, @CharacteristicDimensionmEditFieldValueChanged, true);
            app.CharacteristicDimensionmEditField.Tooltip = {'Characteristic dimension of WEC [m].'};
            app.CharacteristicDimensionmEditField.Value = 1;
            app.HotelLoadWEditFieldLabel = uilabel(app.WECTab);
            app.HotelLoadWEditFieldLabel.Text = 'Hotel Load [W]:';
            app.HotelLoadWWECEditField = uieditfield(app.WECTab, 'numeric');
            app.HotelLoadWWECEditField.Limits = [0 Inf];
            app.HotelLoadWWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.HotelLoadWWECEditField.Tooltip = {'Baseline power required to support WEC operation [W].'};
            app.BatteryCapacityWhLabel = uilabel(app.WECTab);
            app.BatteryCapacityWhLabel.Text = 'Battery Capacity [Wh]:';
            app.BatteryCapacityWhWECEditField = uieditfield(app.WECTab, 'numeric');
            app.BatteryCapacityWhWECEditField.LowerLimitInclusive = 'off';
            app.BatteryCapacityWhWECEditField.Limits = [0 Inf];
            app.BatteryCapacityWhWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCapacityWhWECEditField.Tooltip = {'Total capacity of WEC battery [Wh].'};
            app.BatteryCapacityWhWECEditField.Value = 1;
            app.HydrodynamicEfficiency0XXEditFieldLabel = uilabel(app.WECTab);
            app.HydrodynamicEfficiency0XXEditFieldLabel.Text = 'Hydrodynamic Efficiency (0.XX):';
            app.HydrodynamicEfficiency0XXEditField = uieditfield(app.WECTab, 'numeric');
            app.HydrodynamicEfficiency0XXEditField.Limits = [0 1];
            app.HydrodynamicEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.HydrodynamicEfficiency0XXEditField.Tooltip = {'Efficiency associated with the conversion of wave energy to mechanical energy. Provide as a decimal value.'};
            app.HydrodynamicEfficiency0XXEditField.Value = 1;
            app.GeneratorEfficiency0XXEditFieldLabel = uilabel(app.WECTab);
            app.GeneratorEfficiency0XXEditFieldLabel.Text = 'Generator Efficiency (0.XX):';
            app.GeneratorEfficiency0XXEditField = uieditfield(app.WECTab, 'numeric');
            app.GeneratorEfficiency0XXEditField.Limits = [0 1];
            app.GeneratorEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.GeneratorEfficiency0XXEditField.Tooltip = {'Efficiency associated with generator losses. Provide as a decimal value.'};
            app.GeneratorEfficiency0XXEditField.Value = 1;
            app.BatteryEfficiency0XXLabel = uilabel(app.WECTab);
            app.BatteryEfficiency0XXLabel.Text = 'Battery Efficiency (0.XX):';
            app.BatteryEfficiency0XXWECEditField = uieditfield(app.WECTab, 'numeric');
            app.BatteryEfficiency0XXWECEditField.Limits = [0 1];
            app.BatteryEfficiency0XXWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXWECEditField.Tooltip = {'Efficiency associated with charge and discharge of the WEC battery. Provide as a decimal value.'};
            app.BatteryEfficiency0XXWECEditField.Value = 1;
            app.AUVTab = uitab(app.TabGroup);
            app.AUVTab.Title = 'AUV';
            app.MasskgEditFieldLabel = uilabel(app.AUVTab);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]:';
            app.MissionTimehrEditFieldLabel = uilabel(app.AUVTab);
            app.MissionTimehrEditFieldLabel.Text = 'Mission Time [hr]:';
            app.MissionBatteryUsed0XXEditFieldLabel = uilabel(app.AUVTab);
            app.MissionBatteryUsed0XXEditFieldLabel.Text = 'Mission Battery (%) Used (0.XX):';
            app.BatteryCapacityWhEditField_2Label = uilabel(app.AUVTab);
            app.BatteryCapacityWhEditField_2Label.Text = 'Battery Capacity [Wh]:';
            app.ChargeRateWEditFieldLabel = uilabel(app.AUVTab);
            app.ChargeRateWEditFieldLabel.Text = 'Charge Rate [W]:';
            app.ChargingSwitchLabel = uilabel(app.AUVTab);
            app.ChargingSwitchLabel.Text = 'Charging';
            app.HotelLoadWEditField_2Label = uilabel(app.AUVTab);
            app.HotelLoadWEditField_2Label.Text = 'Hotel Load [W]:';
            app.PowerTransferEfficiency0XXEditFieldLabel = uilabel(app.AUVTab);
            app.PowerTransferEfficiency0XXEditFieldLabel.Text = 'Power Transfer Efficiency (0.XX):';
            app.PowerTransferEfficiency0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.PowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.PowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.PowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.PowerTransferEfficiency0XXEditField.Tooltip = {'Efficiency associated with the power transfer method (wired/wireless) used for AUV recharge. Provide as a decimal value.'};
            app.PowerTransferEfficiency0XXEditField.Value = 1;
            app.BatteryEfficiency0XXEditField_2Label = uilabel(app.AUVTab);
            app.BatteryEfficiency0XXEditField_2Label.Text = 'Battery Efficiency (0.XX):';
            app.BatteryEfficiency0XXAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryEfficiency0XXAUVEditField.LowerLimitInclusive = 'off';
            app.BatteryEfficiency0XXAUVEditField.Limits = [0 1];
            app.BatteryEfficiency0XXAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXAUVEditField.Tooltip = {'Efficiency associated with charge and discharge of the AUV battery. Provide as a decimal value.'};
            app.BatteryEfficiency0XXAUVEditField.Value = 1;
            app.BatteryRechargeThreshold0XXEditFieldLabel = uilabel(app.AUVTab);
            app.BatteryRechargeThreshold0XXEditFieldLabel.Text = 'Battery Recharge Threshold (0.XX):';
            app.BatteryRechargeThreshold0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryRechargeThreshold0XXEditField.Limits = [0 1];
            app.BatteryRechargeThreshold0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryRechargeThreshold0XXEditField.Tooltip = {'Battery threshold prompting AUV recharge. I.e. "low battery". Provide as a decimal value.'};
            app.BatteryRechargeThreshold0XXEditField.Value = 1;
            app.ChargingSwitch = uiswitch(app.AUVTab, 'slider');
            app.ChargingSwitch.Items = {'Wired', 'Wireless'};
            app.ChargingSwitch.ItemsData = [1 0];
            app.ChargingSwitch.ValueChangedFcn = createCallbackFcn(app, @ChargingSwitchValueChanged, true);
            app.ChargingSwitch.Tooltip = {'AUV charging method.'};
            app.ChargingSwitch.Value = 0;
            app.ChargeRateWEditField = uieditfield(app.AUVTab, 'numeric');
            app.ChargeRateWEditField.LowerLimitInclusive = 'off';
            app.ChargeRateWEditField.Limits = [0 Inf];
            app.ChargeRateWEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.ChargeRateWEditField.Tooltip = {'Rate of AUV recharge [W].'};
            app.ChargeRateWEditField.Value = 1;
            app.HotelLoadWAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.HotelLoadWAUVEditField.LowerLimitInclusive = 'off';
            app.HotelLoadWAUVEditField.Limits = [0 Inf];
            app.HotelLoadWAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.HotelLoadWAUVEditField.Tooltip = {'Baseline power required to support AUV operation in-dock [W].'};
            app.HotelLoadWAUVEditField.Value = 1;
            app.MissionBatteryUsed0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.MissionBatteryUsed0XXEditField.LowerLimitInclusive = 'off';
            app.MissionBatteryUsed0XXEditField.Limits = [0 1];
            app.MissionBatteryUsed0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MissionBatteryUsed0XXEditField.Tooltip = {'Percentage of battery used during mission. '};
            app.MissionBatteryUsed0XXEditField.Value = 0.99;
            app.MissionTimehrEditField = uieditfield(app.AUVTab, 'numeric');
            app.MissionTimehrEditField.LowerLimitInclusive = 'off';
            app.MissionTimehrEditField.Limits = [0 Inf];
            app.MissionTimehrEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MissionTimehrEditField.Tooltip = {'Time required [hr] to complete a mission. '};
            app.MissionTimehrEditField.Value = 1;
            app.BatteryCapacityWhAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryCapacityWhAUVEditField.LowerLimitInclusive = 'off';
            app.BatteryCapacityWhAUVEditField.Limits = [0 Inf];
            app.BatteryCapacityWhAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCapacityWhAUVEditField.Tooltip = {'Total capacity of AUV battery [Wh].'};
            app.BatteryCapacityWhAUVEditField.Value = 1;
            app.MasskgEditField = uieditfield(app.AUVTab, 'numeric');
            app.MasskgEditField.LowerLimitInclusive = 'off';
            app.MasskgEditField.Limits = [0 Inf];
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MasskgEditField.Tooltip = {'Mass [kg] of AUV. Used to estimate hotel load in pre-set models.'};
            app.MasskgEditField.Value = 1;
            app.ModelNameLabel = uilabel(app.AUVTab);
            app.ModelNameLabel.Text = 'Model Name:';
            app.ModelNameAUVDropdown = uidropdown(app.AUVTab);
            app.ModelNameAUVDropdown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U'};
            app.ModelNameAUVDropdown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.ModelNameAUVDropdown.Editable = 'on';
            app.ModelNameAUVDropdown.ValueChangedFcn = createCallbackFcn(app, @ModelNameAUVDropdownValueChanged, true);
            app.ModelNameAUVDropdown.Tooltip = {'Name of AUV model, with A-U corresponding to pre-set examples.'};
            app.ModelNameAUVDropdown.Value = '1';
            app.ConfiguredAUVsPanel = uipanel(app.AUVTab);
            app.ConfiguredAUVsPanel.Title = 'Configured AUVs';
            app.savedAUVdisplay = uitextarea(app.ConfiguredAUVsPanel);
            app.savedAUVdisplay.Editable = 'off';
            app.savedAUVdisplay.Tooltip = {'A list of configured AUV(s) ready for simulation.'};
            app.AddAUVButton = uibutton(app.AUVTab, 'push');
            app.AddAUVButton.ButtonPushedFcn = createCallbackFcn(app, @AddAUVButtonPushed, true);
            app.AddAUVButton.Tooltip = {'Use this AUV configuration in the simulation. '};
            app.AddAUVButton.Text = 'Add AUV';
            app.EnergyStorageTab = uitab(app.TabGroup);
            app.EnergyStorageTab.Title = 'Energy Storage';
            app.BatteryEfficiency0XXEditField_3Label = uilabel(app.EnergyStorageTab);
            app.BatteryEfficiency0XXEditField_3Label.Text = 'Battery Efficiency (0.XX): ';
            app.BatteryEfficiency0XXESEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.BatteryEfficiency0XXESEditField.LowerLimitInclusive = 'off';
            app.BatteryEfficiency0XXESEditField.Limits = [0 1];
            app.BatteryEfficiency0XXESEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXESEditField.Tooltip = {'Energy associated with the charge and discharge of the central battery. Provide as a decimal value.'};
            app.BatteryEfficiency0XXESEditField.Value = 1;
            app.AUVDockPowerTransferEfficiency0XXEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.AUVDockPowerTransferEfficiency0XXEditFieldLabel.Text = 'AUV Dock Power Transfer Efficiency (0.XX):';
            app.AUVDockPowerTransferEfficiency0XXEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.AUVDockPowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.AUVDockPowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.AUVDockPowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.AUVDockPowerTransferEfficiency0XXEditField.Tooltip = {'Efficiency associated with the power transfer between the central battery and AUV dock. Provide as a decimal value.'};
            app.AUVDockPowerTransferEfficiency0XXEditField.Value = 1;
            app.WECPowerTransferEfficiency0XXEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.WECPowerTransferEfficiency0XXEditFieldLabel.Text = 'WEC Power Transfer Efficiency (0.XX):';
            app.WECPowerTransferEfficiency0XXEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.WECPowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.WECPowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.WECPowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.WECPowerTransferEfficiency0XXEditField.Tooltip = {'Efficiency associated with the power transfer between the WEC and central battery. Provide as a decimal value.'};
            app.WECPowerTransferEfficiency0XXEditField.Value = 1;
            app.PredefinedCentralBatteryCapacityWhCheckBox = uicheckbox(app.EnergyStorageTab);
            app.PredefinedCentralBatteryCapacityWhCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.PredefinedCentralBatteryCapacityWhCheckBox.Tooltip = {'Select to provide a predetermined central battery capacity. If left unselected, the model will calculate an appropriate capacity based on WEC power generation and the number of AUVs in the system. '};
            app.PredefinedCentralBatteryCapacityWhCheckBox.Text = 'Predefined Central Battery Capacity [Wh]:';
            app.PredefinedCentralBatteryCapacityWhEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.PredefinedCentralBatteryCapacityWhEditField.Limits = [0 Inf];
            app.PredefinedCentralBatteryCapacityWhEditField.AllowEmpty = 'on';
            app.PredefinedCentralBatteryCapacityWhEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.PredefinedCentralBatteryCapacityWhEditField.Enable = 'off';
            app.PredefinedCentralBatteryCapacityWhEditField.Tooltip = {'Total capacity of central battery [Wh]. If empty, will use model''s estimate of ideal capacity.'};
            app.PredefinedCentralBatteryCapacityWhEditField.Value = [];
            app.BaseHotelLoadWEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.BaseHotelLoadWEditFieldLabel.Text = 'Base Hotel Load [W]:';
            app.BaseHotelLoadWEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.BaseHotelLoadWEditField.Limits = [0 Inf];
            app.BaseHotelLoadWEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BaseHotelLoadWEditField.Tooltip = {'Baseline power required to support operation of the central energy unit [W].'};
            app.AUVDockHotelLoadWEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.AUVDockHotelLoadWEditFieldLabel.Text = 'AUV Dock Hotel Load [W]:';
            app.AUVDockHotelLoadWEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.AUVDockHotelLoadWEditField.Limits = [0 Inf];
            app.AUVDockHotelLoadWEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.AUVDockHotelLoadWEditField.Tooltip = {'Baseline power required to support a single AUV dock [W].'};
            app.BatteryCRateLabel = uilabel(app.EnergyStorageTab);
            app.BatteryCRateLabel.Text = 'Battery C-Rate (0.XX):';
            app.BatteryCRate0XXEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.BatteryCRate0XXEditField.Limits = [0 Inf];
            app.BatteryCRate0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCRate0XXEditField.Tooltip = {'Battery c-rate. [1/hr] Set to 0 to disable.'};
            app.SimulationSettingsPanel = uipanel(app.GridLayoutMain);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.StaggerAUVDeploymentsCheckBox = uicheckbox(app.SimulationSettingsPanel);
            app.StaggerAUVDeploymentsCheckBox.Tooltip = {'Select to stagger AUV deployments evenly for multi-AUV systems. This prevents AUVs from being deployed simultaneously. '};
            app.StaggerAUVDeploymentsCheckBox.Text = 'Stagger AUV Deployments';
            app.StaggerAUVDeploymentsCheckBox.Value = true;
            app.LimitMaxAUVFleetSizeCheckBox = uicheckbox(app.SimulationSettingsPanel);
            app.LimitMaxAUVFleetSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.LimitMaxAUVFleetSizeCheckBox.Tooltip = {'Select to limit the maximum AUV fleet size to a set number.'};
            app.LimitMaxAUVFleetSizeCheckBox.Text = 'Limit Max AUV Fleet Size';
            app.LimitMaxAUVFleetSizeEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.LimitMaxAUVFleetSizeEditField.Limits = [0 Inf];
            app.LimitMaxAUVFleetSizeEditField.RoundFractionalValues = 'on';
            app.LimitMaxAUVFleetSizeEditField.AllowEmpty = 'on';
            app.LimitMaxAUVFleetSizeEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.LimitMaxAUVFleetSizeEditField.Enable = 'off';
            app.LimitMaxAUVFleetSizeEditField.Tooltip = {'Maximum number of AUV(s) allowed in a fleet.'};
            app.LimitMaxAUVFleetSizeEditField.Value = [];
            app.ResourceDataSeaStatePanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataSeaStatePanel.Title = 'Resource Data';
            app.ResourceDataSeaStatePanel.Tag = 'resourceDataPanel';
            app.SelectSeaStateLabel = uilabel(app.ResourceDataSeaStatePanel);
            app.SelectSeaStateLabel.Text = 'Select Sea State...';
            app.SelectSeaStateDropDown = uidropdown(app.ResourceDataSeaStatePanel);
            app.SelectSeaStateDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SelectSeaStateDropDown.Tooltip = {'Sea state to evaluate through simulation. Uses 20 minutes of simulated power generation of a two-body floating point absorber WEC at the PacWave South test site. '};
            app.SelectSeaStateDropDown.Value = '3';
            app.ResourceDataMeanWavePanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataMeanWavePanel.Title = 'Resource Data';
            app.ResourceDataMeanWavePanel.Tag = 'resourceDataPanel';
            app.EntermeansignificantwaveheightmEditField = uieditfield(app.ResourceDataMeanWavePanel, 'text');
            app.EntermeansignificantwaveheightmEditField.ValueChangedFcn = createCallbackFcn(app, @ResourceDataEditFieldValueChanged, true);
            app.EntermeansignificantwaveheightmEditField.Tag = 'meanSigWaveHeight';
            app.EntermeansignificantwaveheightmEditField.Tooltip = {'Constant value of significant wave height [m] to use in simulation. Enter a single value or a comma-seperated list if the ''dependent variable'' is ''AUV Model''. '};
            app.EntermeansignificantwaveheightmEditField_2Label_2 = uilabel(app.ResourceDataMeanWavePanel);
            app.EntermeansignificantwaveheightmEditField_2Label_2.Text = 'Enter mean significant wave height [m]:';
            app.EntermeanwaveenergyperiodsEditField_2Label_2 = uilabel(app.ResourceDataMeanWavePanel);
            app.EntermeanwaveenergyperiodsEditField_2Label_2.Text = 'Enter mean wave energy period [s]:';
            app.EntermeanwaveenergyperiodsEditField = uieditfield(app.ResourceDataMeanWavePanel, 'text');
            app.EntermeanwaveenergyperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @ResourceDataEditFieldValueChanged, true);
            app.EntermeanwaveenergyperiodsEditField.Tag = 'meanWaveEnergyPeriod';
            app.EntermeanwaveenergyperiodsEditField.Tooltip = {'Constant value of wave energy period [s] to use in simulation. Enter a single value or a comma-seperated list if the ''dependent variable'' is ''AUV Model''. '};
            app.ResourceDataMeanPowerGenPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataMeanPowerGenPanel.Title = 'Resource Data';
            app.ResourceDataMeanPowerGenPanel.Tag = 'resourceDataPanel';
            app.EntermeanpowergenerationWEditField_3Label = uilabel(app.ResourceDataMeanPowerGenPanel);
            app.EntermeanpowergenerationWEditField_3Label.Text = 'Enter mean power generation [W]:';
            app.EntermeanpowergenerationWEditField = uieditfield(app.ResourceDataMeanPowerGenPanel, 'text');
            app.EntermeanpowergenerationWEditField.ValueChangedFcn = createCallbackFcn(app, @ResourceDataEditFieldValueChanged, true);
            app.EntermeanpowergenerationWEditField.Tag = 'meanPowerGen';
            app.EntermeanpowergenerationWEditField.Tooltip = {'Constant value of power generation [W] to use throughout simulation. Enter a single value or a comma-seperated list if the ''dependent variable'' is ''AUV Model''. '};
            app.ResourceDataPwrTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataPwrTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataPwrTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.BrowsePwrButton = uibutton(app.ResourceDataPwrTimeSeriesUploadPanel, 'push');
            app.BrowsePwrButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowsePwrButton.Tooltip = {'Select a *.mat file containing seperate vectors for the required variables (see dropdowns listed below).'};
            app.BrowsePwrButton.Text = 'Browse';
            app.SelectresourcedatafilePwrLabel = uilabel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.SelectresourcedatafilePwrLabel.Text = 'Select resource data file...';
            app.VariableSelectionPwrData = uipanel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.VariableSelectionPwrData.Tag = 'VariableSelectionPanel';
            app.SelectpowervariableDropDownLabel = uilabel(app.VariableSelectionPwrData);
            app.SelectpowervariableDropDownLabel.Text = 'Select power variable: ';
            app.SelectpowervariableDropDown = uidropdown(app.VariableSelectionPwrData);
            app.SelectpowervariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectpowervariableDropDown.Tag = 'pwrVectorVar';
            app.SelectpowervariableDropDown.Tooltip = {'Select variable corresponding to the power generation vector [W].'};
            app.SelecttimevariableLabel = uilabel(app.VariableSelectionPwrData);
            app.SelecttimevariableLabel.Text = 'Select time variable:';
            app.SelecttimevariableDropDown = uidropdown(app.VariableSelectionPwrData);
            app.SelecttimevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariableDropDown.Tag = 'timeVar';
            app.SelecttimevariableDropDown.Tooltip = {'Select variable corresponding to the time vector [hr].'};
            app.DatafileTextAreaLabel_3 = uilabel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel_3.Text = 'Data file:';
            app.DatafilePwrTextArea = uitextarea(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.DatafilePwrTextArea.Tag = 'dataFileDisplay';
            app.DatafilePwrTextArea.Editable = 'off';
            app.DatafilePwrTextArea.Tooltip = {'Uploaded file(s) will display here.'};
            app.ApplygeneratorefficiencylossesCheckBox_3 = uicheckbox(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.ApplygeneratorefficiencylossesCheckBox_3.Tag = 'applyGenEfficiency';
            app.ApplygeneratorefficiencylossesCheckBox_3.Tooltip = {'Select to apply generator efficiency losses to provided power generation data.'};
            app.ApplygeneratorefficiencylossesCheckBox_3.Text = 'Apply generator efficiency losses';
            app.ResourceDataPwrMatTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataPwrMatTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataPwrMatTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.BrowsePwrMatButton = uibutton(app.ResourceDataPwrMatTimeSeriesUploadPanel, 'push');
            app.BrowsePwrMatButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowsePwrMatButton.Tooltip = {'Select a *.mat file containing seperate arrays for the required variables (see dropdowns listed below).'};
            app.BrowsePwrMatButton.Text = 'Browse';
            app.SelectresourcedatafilePwrMatLabel = uilabel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.SelectresourcedatafilePwrMatLabel.Text = 'Select resource data file...';
            app.VariableSelectionPwrMatData = uipanel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.VariableSelectionPwrMatData.Tag = 'VariableSelectionPanel';
            app.SelectpowermatrixvariableLabel = uilabel(app.VariableSelectionPwrMatData);
            app.SelectpowermatrixvariableLabel.Text = 'Select power matrix variable: ';
            app.SelectpowervariablePwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectpowervariablePwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixVariableDropDownValueChanged, true);
            app.SelectpowervariablePwrMatDropDown.Tag = 'pwrMatrixVar';
            app.SelectpowervariablePwrMatDropDown.Tooltip = {'Select variable corresponding to the (mxn) power matrix with (m,1) containing significant wave height values [m], (1,n) containing wave energy period values [s], and (2:end, 2:end) containing power values [W].'};
            app.SelecttimevariableDropDown_3Label = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label.Text = 'Select time variable:';
            app.SelecttimevariablePwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelecttimevariablePwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariablePwrMatDropDown.Tag = 'timeVar';
            app.SelecttimevariablePwrMatDropDown.Tooltip = {'Select variable corresponding to the time vector [hr].'};
            app.SelecttimevariableDropDown_3Label_2 = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label_2.Text = 'Select significant wave height:';
            app.SelectsignificantwaveheightPwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectsignificantwaveheightPwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectsignificantwaveheightPwrMatDropDown.Tag = 'sigWaveHeightVar';
            app.SelectsignificantwaveheightPwrMatDropDown.Tooltip = {'Select variable corresponding to the significant wave height vector [m].'};
            app.SelecttimevariableDropDown_3Label_3 = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label_3.Text = 'Select wave energy period:';
            app.SelectwaveenergyperiodPwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectwaveenergyperiodPwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectwaveenergyperiodPwrMatDropDown.Tag = 'wavePeriodVar';
            app.SelectwaveenergyperiodPwrMatDropDown.Tooltip = {'Select variable corresponding to the wave energy period vector [s].'};
            app.DatafileTextAreaLabel_2 = uilabel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel_2.Text = 'Data file:';
            app.DatafilePwrMatTextArea = uitextarea(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.DatafilePwrMatTextArea.Tag = 'dataFileDisplay';
            app.DatafilePwrMatTextArea.Editable = 'off';
            app.DatafilePwrMatTextArea.Tooltip = {'Uploaded file(s) will display here.'};
            app.ApplygeneratorefficiencylossesCheckBox_2 = uicheckbox(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.ApplygeneratorefficiencylossesCheckBox_2.Tag = 'applyGenEfficiency';
            app.ApplygeneratorefficiencylossesCheckBox_2.Tooltip = {'Select to apply generator efficiency losses to provided power generation data.'};
            app.ApplygeneratorefficiencylossesCheckBox_2.Text = 'Apply generator efficiency losses';
            app.ResourceDataWaveTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataWaveTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataWaveTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.VariableSelectionWaveData = uipanel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.VariableSelectionWaveData.Tag = 'VariableSelectionPanel';
            app.SelectsignificantwaveheightvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectsignificantwaveheightvariableDropDownLabel.Text = 'Select significant wave height variable:';
            app.SelectsignificantwaveheightvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectsignificantwaveheightvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectsignificantwaveheightvariableDropDown.Tag = 'sigWaveHeightVar';
            app.SelectsignificantwaveheightvariableDropDown.Tooltip = {'Select variable corresponding to the significant wave height vector [m].'};
            app.SelectwaveenergyperiodvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectwaveenergyperiodvariableDropDownLabel.Text = 'Select wave energy period variable: ';
            app.SelectwaveenergyperiodvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectwaveenergyperiodvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectwaveenergyperiodvariableDropDown.Tag = 'wavePeriodVar';
            app.SelectwaveenergyperiodvariableDropDown.Tooltip = {'Select variable corresponding to the wave energy period vector [s].'};
            app.SelectpeakperiodvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectpeakperiodvariableDropDownLabel.Text = 'Select peak period variable:';
            app.SelectpeakperiodvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectpeakperiodvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectpeakperiodvariableDropDown.Tag = 'peakPeriodVar';
            app.SelectpeakperiodvariableDropDown.Tooltip = {'Select variable corresponding to the peak wave period vector [s].'};
            app.SelecttimevariableDropDown_2Label = uilabel(app.VariableSelectionWaveData);
            app.SelecttimevariableDropDown_2Label.Text = 'Select time variable:';
            app.SelecttimevariableDropDown_2 = uidropdown(app.VariableSelectionWaveData);
            app.SelecttimevariableDropDown_2.Items = {};
            app.SelecttimevariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariableDropDown_2.Tag = 'timeVar';
            app.SelecttimevariableDropDown_2.Tooltip = {'Select variable corresponding to the time vector [hr].'};
            app.SelecttimevariableDropDown_2.Value = {};
            app.BrowseWaveButton = uibutton(app.ResourceDataWaveTimeSeriesUploadPanel, 'push');
            app.BrowseWaveButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseWaveButton.Tooltip = {'Select data file. Note *.mat files must contain seperate vectors for the required variables (see dropdowns listed below).'};
            app.BrowseWaveButton.Text = 'Browse';
            app.SelectresourcedatafileWaveLabel = uilabel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.SelectresourcedatafileWaveLabel.Text = 'Select resource data file...';
            app.DataisacsvtablecontainingtherequiredheadersCheckBox = uicheckbox(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.ValueChangedFcn = createCallbackFcn(app, @csvCheckBoxValueChanged, true);
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.Tooltip = {'Select if data file is a *.csv formatted table with the following headings: ''SignificantWaveHeight'', ''EnergyPeriod'', ''PeakPeriod'', ''Year'', ''Month'', ''Day'', ''Hour'', ''Minute''.'};
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.Text = 'Data is a *.csv table containing the required headers';
            app.DatafileTextAreaLabel = uilabel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel.Text = 'Data file: ';
            app.DatafileWaveTextArea = uitextarea(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DatafileWaveTextArea.Tag = 'dataFileDisplay';
            app.DatafileWaveTextArea.Editable = 'off';
            app.DatafileWaveTextArea.Tooltip = {'Uploaded file(s) will display here.'};
            app.SimulationDurationhrLabel = uilabel(app.SimulationSettingsPanel);
            app.SimulationDurationhrLabel.Text = 'Simulation Duration [hr]:';
            app.SimulationDurationhrEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.SimulationDurationhrEditField.LowerLimitInclusive = 'off';
            app.SimulationDurationhrEditField.Limits = [0 8640];
            app.SimulationDurationhrEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.SimulationDurationhrEditField.Tooltip = {'Amount of time to simulate WEC-AUV system operation [hr].'};
            app.SimulationDurationhrEditField.Placeholder = '336';
            app.SimulationDurationhrEditField.Value = 1;
            app.SimulationTimestepsEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.SimulationTimestepsEditFieldLabel.Text = 'Simulation Timestep [s]:';
            app.SimulationTimestepsEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.SimulationTimestepsEditField.LowerLimitInclusive = 'off';
            app.SimulationTimestepsEditField.Limits = [0 86400];
            app.SimulationTimestepsEditField.RoundFractionalValues = 'on';
            app.SimulationTimestepsEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimestepsEditFieldValueChanged, true);
            app.SimulationTimestepsEditField.Tag = 'simTimestep';
            app.SimulationTimestepsEditField.Tooltip = {'Computation timestep [s].'};
            app.SimulationTimestepsEditField.Value = 30;
            app.DependentVariableDropDownLabel = uilabel(app.SimulationSettingsPanel);
            app.DependentVariableDropDownLabel.Text = 'Dependent Variable';
            app.DependentVariableDropDown = uidropdown(app.SimulationSettingsPanel);
            app.DependentVariableDropDown.Items = {'AUV Model', 'WEC Power Gen. / Wave Resource'};
            app.DependentVariableDropDown.ItemsData = [1 2];
            app.DependentVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @DependentVariableDropDownValueChanged, true);
            app.DependentVariableDropDown.Tooltip = {'Variable to change between simulations. '};
            app.DependentVariableDropDown.Value = 1;
            app.ResourceDataTypeDropDownLabel = uilabel(app.SimulationSettingsPanel);
            app.ResourceDataTypeDropDownLabel.Text = 'Resource Data Type';
            app.ResourceDataTypeDropDown = uidropdown(app.SimulationSettingsPanel);
            app.ResourceDataTypeDropDown.Items = {'Sea State Power Data', 'Power Gen. (Time Series)', 'Power Gen. (Mean Value)', 'Wave Specs. (Time Series)', 'Wave Specs. (Mean Value)', 'Power Matrix with Time Series'};
            app.ResourceDataTypeDropDown.ItemsData = [1 2 3 4 5 6];
            app.ResourceDataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.ResourceDataTypeDropDown.Tooltip = {'Type of resource data using for simulations. '};
            app.ResourceDataTypeDropDown.Value = 2;
            app.RunSimulationButton = uibutton(app.GridLayoutMain, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PowerModelGUILabel = uilabel(app.GridLayoutMain);
            app.PowerModelGUILabel.Text = ' Power Model GUI';
            app.WarningTextArea = uitextarea(app.GridLayoutMain);
            app.Panel = uipanel(app.GridLayoutMain);
            app.SimulationGoalDropDownLabel = uilabel(app.Panel);
            app.SimulationGoalDropDownLabel.Text = 'Simulation Goal:';
            app.SimulationGoalDropDown = uidropdown(app.Panel);
            app.SimulationGoalDropDown.Items = {'Calculate AUV fleet size and central battery capacity', 'Calculate power required to support given AUV fleet'};
            app.SimulationGoalDropDown.ItemsData = {'1', '2'};
            app.SimulationGoalDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationGoalDropDownValueChanged, true);
            app.SimulationGoalDropDown.Value = '1';
            app.Panel_2 = uipanel(app.GridLayoutMain);
            app.ResetButton = uibutton(app.Panel_2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powerModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end